classdef ImageApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DIP20WTermProjectAppUIFigure    matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LoadImageMenu                   matlab.ui.container.Menu
        SaveImageMenu                   matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        GeneralTab                      matlab.ui.container.Tab
        GrayRawImgAxes                  matlab.ui.control.UIAxes
        HistogramAxes                   matlab.ui.control.UIAxes
        ImageSizeLabel                  matlab.ui.control.Label
        ImageSize                       matlab.ui.control.EditField
        ImageMeanEditFieldLabel         matlab.ui.control.Label
        ImageMean                       matlab.ui.control.EditField
        StandardDeviationEditFieldLabel  matlab.ui.control.Label
        StandardDeviation               matlab.ui.control.EditField
        FFTAxes                         matlab.ui.control.UIAxes
        GrayLevelsTab                   matlab.ui.container.Tab
        ReduceGrayLevelButton           matlab.ui.control.Button
        ShowInFigure                    matlab.ui.control.CheckBox
        GrayLevel255UIAxes              matlab.ui.control.UIAxes
        GrayLevel128UIAxes              matlab.ui.control.UIAxes
        GrayLevel64UIAxes               matlab.ui.control.UIAxes
        GrayLevel32UIAxes               matlab.ui.control.UIAxes
        GrayLevel16UIAxes               matlab.ui.control.UIAxes
        GrayLevel8UIAxes                matlab.ui.control.UIAxes
        GrayLevel4UIAxes                matlab.ui.control.UIAxes
        GrayLevel2UIAxes                matlab.ui.control.UIAxes
        ResizeTab                       matlab.ui.container.Tab
        ResizeRatioEditFieldLabel       matlab.ui.control.Label
        ResizeRatioEditField            matlab.ui.control.NumericEditField
        ResizeUIAxes0                   matlab.ui.control.UIAxes
        ResizeUIAxes1                   matlab.ui.control.UIAxes
        ResizeUIAxes2                   matlab.ui.control.UIAxes
        ResizeUIAxes3                   matlab.ui.control.UIAxes
        IntensityTransformationTab      matlab.ui.container.Tab
        TransformationUIAxes            matlab.ui.control.UIAxes
        AfterUIAxes                     matlab.ui.control.UIAxes
        TransformationsButtonGroup      matlab.ui.container.ButtonGroup
        NegativeButton                  matlab.ui.control.RadioButton
        LogrithmButton                  matlab.ui.control.RadioButton
        PowerLawButton                  matlab.ui.control.RadioButton
        PiecewiselinearButton           matlab.ui.control.RadioButton
        HistogramEqualizationButton     matlab.ui.control.RadioButton
        Label                           matlab.ui.control.Label
        PowerLawY                       matlab.ui.control.NumericEditField
        vLabel                          matlab.ui.control.Label
        LogPara_v                       matlab.ui.control.NumericEditField
        Piecewise_ay                    matlab.ui.control.NumericEditField
        aLabel                          matlab.ui.control.Label
        Piecewise_ax                    matlab.ui.control.NumericEditField
        Piecewise_bx                    matlab.ui.control.NumericEditField
        Piecewise_by                    matlab.ui.control.NumericEditField
        bLabel                          matlab.ui.control.Label
        cLabel                          matlab.ui.control.Label
        LogPara_c                       matlab.ui.control.NumericEditField
        LocalHistogramEqualizationButton  matlab.ui.control.RadioButton
        MaskEditFieldLabel_2            matlab.ui.control.Label
        LocalHE_Masksize                matlab.ui.control.NumericEditField
        LocalHistogramStatisticsButton  matlab.ui.control.RadioButton
        EEditFieldLabel                 matlab.ui.control.Label
        LHS_E                           matlab.ui.control.NumericEditField
        k0EditFieldLabel                matlab.ui.control.Label
        LHS_k0                          matlab.ui.control.NumericEditField
        k1EditFieldLabel                matlab.ui.control.Label
        LHS_k1                          matlab.ui.control.NumericEditField
        k2EditFieldLabel                matlab.ui.control.Label
        LHS_k2                          matlab.ui.control.NumericEditField
        IntensityLevelSlicingButton     matlab.ui.control.RadioButton
        axEditFieldLabel                matlab.ui.control.Label
        IntensityLevelSlicing_ax        matlab.ui.control.NumericEditField
        ayEditFieldLabel                matlab.ui.control.Label
        IntensityLevelSlicing_ay        matlab.ui.control.NumericEditField
        bxEditFieldLabel                matlab.ui.control.Label
        IntensityLevelSlicing_bx        matlab.ui.control.NumericEditField
        byEditField_5Label              matlab.ui.control.Label
        IntensityLevelSlicing_by        matlab.ui.control.NumericEditField
        Level1EditFieldLabel            matlab.ui.control.Label
        IntensityLevelSlicing_L1        matlab.ui.control.NumericEditField
        Level2EditFieldLabel            matlab.ui.control.Label
        IntensityLevelSlicing_L2        matlab.ui.control.NumericEditField
        TransformButton                 matlab.ui.control.Button
        OriginalUIAxes                  matlab.ui.control.UIAxes
        AfterHistUIAxes                 matlab.ui.control.UIAxes
        OriginalHistUIAxes              matlab.ui.control.UIAxes
        IntensityKeepProcessButton      matlab.ui.control.Button
        SDFilteringTab                  matlab.ui.container.Tab
        FilterTypeDropDownLabel         matlab.ui.control.Label
        FilterTypeDropDown              matlab.ui.control.DropDown
        SDFilterButton                  matlab.ui.control.Button
        SmoothingPanel                  matlab.ui.container.Panel
        AverageCheckBox                 matlab.ui.control.CheckBox
        MedianCheckBox                  matlab.ui.control.CheckBox
        GaussianCheckBox                matlab.ui.control.CheckBox
        AverageMask1                    matlab.ui.control.NumericEditField
        AverageMask2                    matlab.ui.control.NumericEditField
        AverageMask3                    matlab.ui.control.NumericEditField
        MedianMask1                     matlab.ui.control.NumericEditField
        MedianMask2                     matlab.ui.control.NumericEditField
        MedianMask3                     matlab.ui.control.NumericEditField
        GaussianSigma1                  matlab.ui.control.NumericEditField
        GaussianSigma2                  matlab.ui.control.NumericEditField
        GaussianSigma3                  matlab.ui.control.NumericEditField
        ContraharmMask1                 matlab.ui.control.NumericEditField
        ContraharmMask2                 matlab.ui.control.NumericEditField
        ContraharmMask3                 matlab.ui.control.NumericEditField
        QEditFieldLabel                 matlab.ui.control.Label
        ContraharmQ                     matlab.ui.control.NumericEditField
        MaskLabel_2                     matlab.ui.control.Label
        MaskLabel_3                     matlab.ui.control.Label
        SigmaLabel                      matlab.ui.control.Label
        MaskEditFieldLabel_4            matlab.ui.control.Label
        SD_Gaussian_mask                matlab.ui.control.NumericEditField
        SharpeningPanel                 matlab.ui.container.Panel
        LaplacianCheckBox               matlab.ui.control.CheckBox
        PrewittCheckBox                 matlab.ui.control.CheckBox
        SobelCheckBox                   matlab.ui.control.CheckBox
        LaplacianAlpha                  matlab.ui.control.NumericEditField
        AlphaLabel                      matlab.ui.control.Label
        kEditFieldLabel_2               matlab.ui.control.Label
        Laplacian_k                     matlab.ui.control.NumericEditField
        kEditField_2Label               matlab.ui.control.Label
        Prewitt_k                       matlab.ui.control.NumericEditField
        kEditField_3Label               matlab.ui.control.Label
        Sobel_k                         matlab.ui.control.NumericEditField
        transposeCheckBox               matlab.ui.control.CheckBox
        SDFilteringUIAxes0              matlab.ui.control.UIAxes
        SDFilteringUIAxes1              matlab.ui.control.UIAxes
        SDFilteringUIAxes2              matlab.ui.control.UIAxes
        SDFilteringUIAxes3              matlab.ui.control.UIAxes
        ContraharmCheckBox              matlab.ui.control.CheckBox
        MaskLabel                       matlab.ui.control.Label
        HighboostCheckBox               matlab.ui.control.CheckBox
        kEditFieldLabel                 matlab.ui.control.Label
        HighBoost_k                     matlab.ui.control.NumericEditField
        HighBoostFilterButton           matlab.ui.control.Button
        SDFilterKeepProcessingButton    matlab.ui.control.Button
        ResultnumberEditFieldLabel      matlab.ui.control.Label
        ResultnumberEditField           matlab.ui.control.NumericEditField
        FDFilteringTab                  matlab.ui.container.Tab
        FilterTypeDropDown_2Label       matlab.ui.control.Label
        FilterTypeDropDown_2            matlab.ui.control.DropDown
        FDFilterButton                  matlab.ui.control.Button
        SmoothingPanel_2                matlab.ui.container.Panel
        IdealLowPassCheckBox            matlab.ui.control.CheckBox
        GaussianLowPassCheckBox         matlab.ui.control.CheckBox
        ButterworthLowPassCheckBox      matlab.ui.control.CheckBox
        D0EditFieldLabel                matlab.ui.control.Label
        IdealLowPassD0                  matlab.ui.control.NumericEditField
        D0EditField_2Label              matlab.ui.control.Label
        GaussianLowPassD0               matlab.ui.control.NumericEditField
        D0EditField_3Label              matlab.ui.control.Label
        ButterworthLowPassD0            matlab.ui.control.NumericEditField
        nEditFieldLabel                 matlab.ui.control.Label
        ButterworthLowPassn             matlab.ui.control.NumericEditField
        ButterworthNotchFilterCheckBox  matlab.ui.control.CheckBox
        D0EditField_3Label_2            matlab.ui.control.Label
        ButterworthNotchFilterD0        matlab.ui.control.NumericEditField
        Uk1EditFieldLabel               matlab.ui.control.Label
        Uk1EditField                    matlab.ui.control.NumericEditField
        Vk1EditFieldLabel               matlab.ui.control.Label
        Vk1EditField                    matlab.ui.control.NumericEditField
        Uk2EditFieldLabel               matlab.ui.control.Label
        Uk2EditField                    matlab.ui.control.NumericEditField
        Vk2EditFieldLabel               matlab.ui.control.Label
        Vk2EditField                    matlab.ui.control.NumericEditField
        Uk3EditFieldLabel               matlab.ui.control.Label
        Uk3EditField                    matlab.ui.control.NumericEditField
        Vk3EditFieldLabel               matlab.ui.control.Label
        Vk3EditField                    matlab.ui.control.NumericEditField
        Uk4EditFieldLabel               matlab.ui.control.Label
        Uk4EditField                    matlab.ui.control.NumericEditField
        Vk4EditFieldLabel               matlab.ui.control.Label
        Vk4EditField                    matlab.ui.control.NumericEditField
        SharpeningPanel_2               matlab.ui.container.Panel
        IdealHighPassCheckBox           matlab.ui.control.CheckBox
        GaussianHighPassCheckBox        matlab.ui.control.CheckBox
        ButterworthHighPassCheckBox     matlab.ui.control.CheckBox
        D0EditField_4Label              matlab.ui.control.Label
        IdealHighPassD0                 matlab.ui.control.NumericEditField
        D0EditField_5Label              matlab.ui.control.Label
        GaussianHighPassD0              matlab.ui.control.NumericEditField
        D0EditField_6Label              matlab.ui.control.Label
        ButterworthHighPassD0           matlab.ui.control.NumericEditField
        nEditField_2Label               matlab.ui.control.Label
        ButterworthHighPassn            matlab.ui.control.NumericEditField
        FDFilteringUIAxes0              matlab.ui.control.UIAxes
        FDFilteringUIAxes1              matlab.ui.control.UIAxes
        FDFilteringUIAxes2              matlab.ui.control.UIAxes
        FDFilteringUIAxes3              matlab.ui.control.UIAxes
        FDFilteringKeepProcessingButton  matlab.ui.control.Button
        UnsharpMaskingButton            matlab.ui.control.Button
        kEditFieldLabel_3               matlab.ui.control.Label
        UnsharpMasking_k                matlab.ui.control.NumericEditField
        NoiseReductionTab               matlab.ui.container.Tab
        NoiseReductionPanel             matlab.ui.container.Panel
        ArithmeticCheckBox              matlab.ui.control.CheckBox
        GeometricCheckBox               matlab.ui.control.CheckBox
        ContraharmonicCheckBox          matlab.ui.control.CheckBox
        OrderStatisticsCheckBox         matlab.ui.control.CheckBox
        AdaptivemeanfiltersCheckBox     matlab.ui.control.CheckBox
        MaskEditFieldLabel              matlab.ui.control.Label
        ArithmeticMask1                 matlab.ui.control.NumericEditField
        MaskEditField_2Label            matlab.ui.control.Label
        GeometricMask1                  matlab.ui.control.NumericEditField
        MaskEditField_3Label            matlab.ui.control.Label
        ContraHarmonicMask1             matlab.ui.control.NumericEditField
        MaskEditField_4Label            matlab.ui.control.Label
        OrderStatisticsMask             matlab.ui.control.NumericEditField
        OrderEditFieldLabel             matlab.ui.control.Label
        OrderStatisticsOrder            matlab.ui.control.NumericEditField
        QEditFieldLabel_2               matlab.ui.control.Label
        ContraHarmonicQ                 matlab.ui.control.NumericEditField
        WienerFilterCheckBox            matlab.ui.control.CheckBox
        MaskEditField_4Label_2          matlab.ui.control.Label
        WienerFilter_mask               matlab.ui.control.NumericEditField
        AlphaTrimMeanCheckBox           matlab.ui.control.CheckBox
        MaskEditField_4Label_3          matlab.ui.control.Label
        AlphaTrimMean_mask              matlab.ui.control.NumericEditField
        NoiseReduceButton               matlab.ui.control.Button
        NoiseReductionUIAxes0           matlab.ui.control.UIAxes
        NoiseReductionUIAxes1           matlab.ui.control.UIAxes
        NoiseReductionUIAxes2           matlab.ui.control.UIAxes
        NoiseReductionUIAxes3           matlab.ui.control.UIAxes
        NoiseadditionPanel              matlab.ui.container.Panel
        GaussianNoiseCheckBox           matlab.ui.control.CheckBox
        meanEditFieldLabel              matlab.ui.control.Label
        GaussianNoisemean               matlab.ui.control.NumericEditField
        varianceEditFieldLabel          matlab.ui.control.Label
        GaussianNoiseVar                matlab.ui.control.NumericEditField
        SaltpepperNoiseCheckBox         matlab.ui.control.CheckBox
        PaLabel                         matlab.ui.control.Label
        SaltPepperPa                    matlab.ui.control.NumericEditField
        RayleighNoiseCheckBox           matlab.ui.control.CheckBox
        ExponentialNoiseCheckBox        matlab.ui.control.CheckBox
        ErlangNoiseCheckBox             matlab.ui.control.CheckBox
        aEditFieldLabel                 matlab.ui.control.Label
        RayleighNoiseA                  matlab.ui.control.NumericEditField
        bEditFieldLabel                 matlab.ui.control.Label
        RayleighNoiseB                  matlab.ui.control.NumericEditField
        aEditField_2Label               matlab.ui.control.Label
        ExponentialNoiseA               matlab.ui.control.NumericEditField
        aEditField_3Label               matlab.ui.control.Label
        ErlangNoiseA                    matlab.ui.control.NumericEditField
        bEditField_2Label               matlab.ui.control.Label
        ErlangNoiseB                    matlab.ui.control.NumericEditField
        PbLabel                         matlab.ui.control.Label
        SaltPepperPb                    matlab.ui.control.NumericEditField
        NosieReduceKeepProcessingButton  matlab.ui.control.Button
    end


    properties (Access = public)
        GrayRawImage % Intensity range 0~1
        GrayRawImage255 % Intensity range 0~255
    end
    
    properties (Access = private)
        ImageRowNum % Image row size 
        ImageColumnNum % Image column size
        SDFilteredImg1 % 1st Filtered image in SD filtering page
        SDFilteredImg2 % 2nd Filtered image in SD filtering page
        SDFilteredImg3 % 3rd Filtered image in SD filtering page
        ProcessedImg % Processsed image
        FDFilteredImg % After FD filter processed image

    end
    
    methods (Access = private)
        
        function img = PowerLawTransform(app)
            
            f = app.GrayRawImage;
            C = 1;
            Gamma = app.PowerLawY.Value;
            img = C*(f.^Gamma);

        end
        
        function img = LogTransform(app,c,v)

            r = app.GrayRawImage;
            s = c*log2(1 + v*r)/log2(v+1);
            %s=log(1+r);
            img=mat2gray(s);
        end
        
        function img = PieceWiseLinearTransform(app)
            f0=double(0);g0=double(0);
            f1=double(app.Piecewise_ax.Value); g1=double(app.Piecewise_ay.Value);
            f2=double(app.Piecewise_bx.Value); g2=double(app.Piecewise_by.Value);
            f3=double(1);g3=double(1);
            plot(app.TransformationUIAxes,[f0,f1,f2,f3],[g0,g1,g2,g3]); 
            
            r1=(g1-g0)/(f1-f0);
            b1=g0-r1*f0;
            r2=(g2-g1)/(f2-f1);
            b2=g1-r2*f1;
            r3=(g3-g2)/(f3-f2);
            b3=g2-r3*f2;
            [m,n]=size(app.GrayRawImage);
            X2=double(app.GrayRawImage);
            for i=1:m
                for j=1:n
                    f=X2(i,j);
                    g(i,j)=0;
                    if(f>=f1)&(f<=f2)
                        g(i,j)=r1*f+b2;
                    elseif(f>=f2)&(f<=f3)
                        g(i,j)=r3*f+b3;
                    end
                end
            end
            
            img=g;
        end
        
        function imt = ReduceGrayLevel(app,img, factor)
            if factor < 0
                factor = 0;
            elseif factor > 8
                factor = 8;    
            end

            dfact = uint8(power(2,factor));
            imt = floor((img ./ dfact)) * dfact;
        end
        
        
        function reformedimage = ContraHarmFiltering(app, img, Q, masksize)
            data=im2double(img);
            
            pixln=0;
            pixld=0;
            [ro,col]=size(data);
            reformedimage = zeros(ro, col);
            %% Main Module for Contra Harmonic Mean Filter
            for i=1:ro
                for j=1:col
                    for m=-masksize:masksize
                        for n=-masksize:masksize
                            if (i+m>0 && i+m<ro && j+n>0 && j+n<col && ...      % To keep indices in limit
                                    masksize+m>0 && masksize+m<ro && ...
                                    masksize+n>0 && masksize+n<col) 
                                
                                pixl1=(data(i+m,j+n)).^(Q+1);                   % Application of Formula
                                pixl2= (data(i+m,j+n)).^Q;
                                pixln=pixln+pixl1;                              % Application of Summation
                                pixld=pixld+pixl2;
                            
                            end
                        end
                    end
                   reformedimage(i,j)=(pixln/pixld);
                   pixln=0;
                   pixld=0;
                end
            end            
        end
        
        function [SelectionNum, SelectedFunc] = CheckSDFilteringFuncSelection(app)
            SelectionNum = app.AverageCheckBox.Value + app.MedianCheckBox.Value + app.GaussianCheckBox.Value + app.ContraharmCheckBox.Value + ...
                           app.LaplacianCheckBox.Value + app.SobelCheckBox.Value + app.PrewittCheckBox.Value + app.AlphaTrimMeanCheckBox.Value;
            SelectedFunc = '';
            if(SelectionNum == 1)
                if(app.AverageCheckBox.Value == 1)
                    SelectedFunc = 'Average';
                elseif (app.MedianCheckBox.Value == 1)
                    SelectedFunc = 'Median';
                elseif (app.GaussianCheckBox.Value == 1)
                    SelectedFunc = 'Gaussian';
                elseif (app.ContraharmCheckBox.Value == 1)
                    SelectedFunc = 'ContraHarm';
                elseif (app.LaplacianCheckBox.Value == 1)
                    SelectedFunc = 'Laplacian';
                elseif (app.SobelCheckBox.Value == 1)
                    SelectedFunc = 'Sobel';
                elseif (app.PrewittCheckBox.Value == 1)
                    SelectedFunc = 'Prewitt';
                end
            end
        end
        
        
        function [SelectionNum, SelectedFunc] = CheckFDFilteringFuncSelection(app)
            SelectionNum = app.IdealLowPassCheckBox.Value + app.GaussianLowPassCheckBox.Value + app.ButterworthLowPassCheckBox.Value + ...
                           app.IdealHighPassCheckBox.Value + app.GaussianHighPassCheckBox.Value + app.ButterworthHighPassCheckBox.Value + ...
                           + app.ButterworthNotchFilterCheckBox.Value;
            SelectedFunc = '';
            if(SelectionNum == 1)
                if(app.IdealLowPassCheckBox.Value == 1)
                    SelectedFunc = 'IdealLowPass';
                elseif (app.GaussianLowPassCheckBox.Value == 1)
                    SelectedFunc = 'GaussianLowPass';
                elseif (app.ButterworthLowPassCheckBox.Value == 1)
                    SelectedFunc = 'ButterworthLowPass';
                elseif (app.IdealHighPassCheckBox.Value == 1)
                    SelectedFunc = 'IdealHighPass';
                elseif (app.GaussianHighPassCheckBox.Value == 1)
                    SelectedFunc = 'GaussianHighPass';
                elseif (app.ButterworthHighPassCheckBox.Value == 1)
                    SelectedFunc = 'ButterworthHighPass';
                elseif (app.ButterworthNotchFilterCheckBox.Value ==1)
                    SelectedFunc = 'ButterworthNotchFilter';
                end
            end
        end
        
        function [SelectionNum, SelectedFunc] = CheckNoiseReductionFuncSelection(app)
            SelectionNum = app.ArithmeticCheckBox.Value + app.GeometricCheckBox.Value + app.ContraharmonicCheckBox.Value + ...
                           app.OrderStatisticsCheckBox.Value + app.AdaptivemeanfiltersCheckBox.Value+app.WienerFilterCheckBox.Value;
            SelectedFunc = '';
            if(SelectionNum == 1)
                if(app.ArithmeticCheckBox.Value == 1)
                    SelectedFunc = 'Arithmetic';
                elseif (app.GeometricCheckBox.Value == 1)
                    SelectedFunc = 'Geometric';
                elseif (app.ContraharmonicCheckBox.Value == 1)
                    SelectedFunc = 'Contraharmonic';
                elseif (app.OrderStatisticsCheckBox.Value == 1)
                    SelectedFunc = 'OrderStatisticsfilters';
                elseif (app.AdaptivemeanfiltersCheckBox.Value == 1)
                    SelectedFunc = 'Adaptivefilters';
                    
                elseif (app.WienerFilterCheckBox.Value == 1)
                    SelectedFunc = 'WienerFilter';
                elseif(app.AlphaTrimMeanCheckBox.Value ==1)
                    SelectedFunc = 'AlphaTrimMean';
                end
            end
        end
        
        function f = GeometricMeanFiltering(app,g,masksize)
            g = im2double(g);
            %f = exp(imfilter(log(g),ones(m,n),'replicate')).^(1/m/n);
            
            M=size(app.GrayRawImage,1);
            N=size(app.GrayRawImage,2);
            
            % Neighborhood size m x n(assume m,n are old)
            %pad the image by appropriate size
            detal=(masksize-1)/2;
            padded_image = padarray(app.GrayRawImage,[detal detal],0,'both');
            filtered_image = zeros(M, N);
            for i=1: M
                for j=1: N
                    %extract a neighborhood
                    S_xy=padded_image(i:i+2*detal,j:j+2*detal);
                    %do specified operation over the neighborhood
                    %compute geometric mean of pixel values in S_xy
                    filtered_image(i,j)=(prod(S_xy(:)))^(1/(masksize*masksize));
                end
            end
            f= filtered_image;
        end
        
        function filterImg = AdaptiveMedianFiltering(app,img)
            [Im,In] = size(img);
            nmin = 3;
            nmax = 9;
            Imf = img;
            I_ex = [zeros((nmax-1)/2,In+(nmax-1));zeros(Im,(nmax-1)/2),img,zeros(Im,(nmax-1)/2);zeros((nmax-1)/2,In+(nmax-1))];
            for x = 1:Im     
                for y = 1:In          
                    for n = nmin:2:nmax                                
                      Sxy = I_ex(x+(nmax-1)/2-(n-1)/2:x+(nmax-1)/2+(n-1)/2,y+(nmax-1)/2-(n-1)/2:y+(nmax-1)/2+(n-1)/2);                  
                        Smax = max(max(Sxy));                
                        Smin = min(min(Sxy));               
                        Smed = median(median(Sxy));             
             
                        if Smed > Smin && Smed < Smax                
                
                            if Imf(x,y) <= Smin || Imf(x,y) >= Smax                      
                
                                Imf(x,y) = Smed;                 
                            end
                            break             
                        end
                    end      
                    Imf(x,y) = Smed;   
                end
            end
            filterImg = Imf;
        end
        
        function filteredImg = ArithmeticMeanFilter(app, img)
            a=ones(n);
            [M,N]=size(x);
            x1=double(x);
            x2=x1;
            for i=1:M-n+1
                 for j=N-n+1
                        c=x1(i:i+n-1,j:j+n-1).*a;
                        s=sum(sum(c));
                        x2(i+fix((n-1)/2),j+fix((n-1)/2))=s/(n*n);  
                 end
            end
        end
        
        function R = imnoise2(app,type, M, N, a, b)
            %IMNOISE2 Generates an array of random numbers with specified PDF.
            %   R = IMNOISE2(TYPE, M, N, A, B) generates an array, R, of size
            %   M-by-N, whose elements are random numbers of the specified TYPE
            %   with parameters A and B. If only TYPE is included in the
            %   input argument list, a  single random number of the specified
            %   TYPE and default parameters shown below is generated. If only
            %   TYPE, M, and N are provided, the default parameters shown below
            %   are used.  If M = N = 1, IMNOISE2 generates a single random
            %   number of the specified TYPE and parameters A and B.
            %
            %   Valid values for TYPE and parameters A and B are:
            % 
            %   'uniform'       Uniform random numbers in the interval (A, B).
            %                   The default values are (0, 1).  
            %   'gaussian'      Gaussian random numbers with mean A and standard
            %                   deviation B. The default values are A = 0, B = 1.
            %   'salt & pepper' Salt and pepper numbers of amplitude 0 with
            %                   probability Pa = A, and amplitude 1 with
            %                   probability Pb = B. The default values are Pa =
            %                   Pb = A = B = 0.05.  Note that the noise has
            %                   values 0 (with probability Pa = A) and 1 (with
            %                   probability Pb = B), so scaling is necessary if
            %                   values other than 0 and 1 are required. The noise
            %                   matrix R is assigned three values. If R(x, y) =
            %                   0, the noise at (x, y) is pepper (black).  If
            %                   R(x, y) = 1, the noise at (x, y) is salt
            %                   (white). If R(x, y) = 0.5, there is no noise
            %                   assigned to coordinates (x, y). 
            %   'lognormal'     Lognormal numbers with offset A and shape
            %                   parameter B. The defaults are A = 1 and B =
            %                   0.25.
            %   'rayleigh'      Rayleigh noise with parameters A and B. The
            %                   default values are A = 0 and B = 1. 
            %   'exponential'   Exponential random numbers with parameter A.  The
            %                   default is A = 1.
            %   'erlang'        Erlang (gamma) random numbers with parameters A
            %                   and B.  B must be a positive integer. The
            %                   defaults are A = 2 and B = 5.  Erlang random
            %                   numbers are approximated as the sum of B
            %                   exponential random numbers.
            
            %   Copyright 2002-2004 R. C. Gonzalez, R. E. Woods, & S. L. Eddins
            %   Digital Image Processing Using MATLAB, Prentice-Hall, 2004
            %   $Revision: 1.5 $  $Date: 2003/10/12 23:37:29 $
            
            % Set default values.
            if nargin == 1
               a = 0; b = 1;
               M = 1; N = 1;
            elseif nargin == 3
               a = 0; b = 1;
            end
            
            % Begin processing. Use lower(type) to protect against input being
            % capitalized. 
            switch lower(type)
            case 'uniform'
               R = a + (b - a)*rand(M, N);
            case 'gaussian'
               R = a + b*randn(M, N);
            case 'salt & pepper'
               if nargin <= 3
                  a = 0.05; b = 0.05;
               end
               % Check to make sure that Pa + Pb is not > 1.
               if (a + b) > 1
                  error('The sum Pa + Pb must not exceed 1.')
               end
               R= app.GrayRawImage;
               % Generate an M-by-N array of uniformly-distributed random numbers
               % in the range (0, 1). Then, Pa*(M*N) of them will have values <=
               % a. The coordinates of these points we call 0 (pepper
               % noise). Similarly, Pb*(M*N) points will have values in the range
               % > a & <= (a + b).  These we call 1 (salt noise). 
               X = rand(M, N);
               c = find(X <= a);
               R(c) = 0;
               u = a + b;
               d = find(X > a & X < u);
               R(d) = 1;
            case 'lognormal'
               if nargin <= 3
                  a = 1; b = 0.25;
               end
               R = a*exp(b*randn(M, N));
            case 'rayleigh'
               R = a + (-b*log(1 - rand(M, N))).^0.5;
            case 'exponential'
               if nargin <= 3
                  a = 1;
               end
               if a <= 0
                  error('Parameter a must be positive for exponential type.')
               end
               k = -1/a;
               R = k*log(1 - rand(M, N));
            case 'erlang'
               if nargin <= 3
                  a = 2; b = 5;
               end
               if (b ~= round(b) | b <= 0)
                  error('Param b must be a positive integer for Erlang.')
               end
               k = -1/a;
               R = zeros(M, N);
               for j = 1:b         
                  R = R + k*log(1 - rand(M, N));
               end
            otherwise
               error('Unknown distribution type.')
            end
        end
        
        function [SelectionNum,SelectedFunc] = CheckNoiseAdditionFunction(app)
            SelectionNum = app.GaussianNoiseCheckBox.Value + app.SaltpepperNoiseCheckBox.Value +  ...
                           app.RayleighNoiseCheckBox.Value + app.ExponentialNoiseCheckBox.Value + app.ErlangNoiseCheckBox.Value;
            SelectedFunc = 0;
            if(SelectionNum == 1)
                if(app.GaussianNoiseCheckBox.Value == 1)
                    SelectedFunc = 'GaussianNoise';
                elseif (app.SaltpepperNoiseCheckBox.Value == 1)
                    SelectedFunc = 'SaltpepperNoise';
                elseif (app.RayleighNoiseCheckBox.Value == 1)
                    SelectedFunc = 'RayleighNoise';
                elseif (app.ExponentialNoiseCheckBox.Value == 1)
                    SelectedFunc = 'ExponentialNoise';
                elseif (app.ErlangNoiseCheckBox.Value == 1)
                    SelectedFunc = 'ErlangNoise';
                end
            end
        end
        
        function H_Filter = ButterworthNotchFilter(app)
            [M,N] = size(app.GrayRawImage);
            P = 2*M;
            Q = 2*N;
            
            H_NF = ones(P,Q);
            
            for x = (-P/2):1:(P/2)-1
                 for y = (-Q/2):1:(Q/2)-1
                    D = app.ButterworthNotchFilterD0.Value;
                    
                    v_k = app.Vk1EditField.Value; u_k = app.Uk1EditField.Value;
                    D_k = ((x+u_k)^2 + (y+v_k)^2)^(0.5);
                    H_NF(x+(P/2)+1,y+(Q/2)+1) = H_NF(x+(P/2)+1,y+(Q/2)+1) * 1/(1+(D/D_k)^4);
                    D_k = ((x-u_k)^2 + (y-v_k)^2)^(0.5);
                    H_NF(x+(P/2)+1,y+(Q/2)+1) = H_NF(x+(P/2)+1,y+(Q/2)+1) * 1/(1+(D/D_k)^4);
                    
                    v_k = app.Vk2EditField.Value; u_k = app.Uk2EditField.Value;
                    D_k = ((x+u_k)^2 + (y+v_k)^2)^(0.5);
                    H_NF(x+(P/2)+1,y+(Q/2)+1) = H_NF(x+(P/2)+1,y+(Q/2)+1) * 1/(1+(D/D_k)^4);
                    D_k = ((x-u_k)^2 + (y-v_k)^2)^(0.5);
                    H_NF(x+(P/2)+1,y+(Q/2)+1) = H_NF(x+(P/2)+1,y+(Q/2)+1) * 1/(1+(D/D_k)^4);
                    
                    v_k = app.Vk3EditField.Value; u_k = app.Uk3EditField.Value;
                    D_k = ((x+u_k)^2 + (y+v_k)^2)^(0.5);
                    H_NF(x+(P/2)+1,y+(Q/2)+1) = H_NF(x+(P/2)+1,y+(Q/2)+1) * 1/(1+(D/D_k)^4);
                    D_k = ((x-u_k)^2 + (y-v_k)^2)^(0.5);
                    H_NF(x+(P/2)+1,y+(Q/2)+1) = H_NF(x+(P/2)+1,y+(Q/2)+1) * 1/(1+(D/D_k)^4);
                    
                    v_k = app.Vk4EditField.Value; u_k = app.Uk4EditField.Value;
                    D_k = ((x+u_k)^2 + (y+v_k)^2)^(0.5);
                    H_NF(x+(P/2)+1,y+(Q/2)+1) = H_NF(x+(P/2)+1,y+(Q/2)+1) * 1/(1+(D/D_k)^4);
                    D_k = ((x-u_k)^2 + (y-v_k)^2)^(0.5);
                    H_NF(x+(P/2)+1,y+(Q/2)+1) = H_NF(x+(P/2)+1,y+(Q/2)+1) * 1/(1+(D/D_k)^4);
                 end
            end
            H_Filter = H_NF;
        end
        
        function results = ShowImageBaseInfo(app)

            imshow(app.GrayRawImage,'parent',app.GrayRawImgAxes);
            
            histogram(app.HistogramAxes, app.GrayRawImage,255);
            
            [row,column]=size(app.GrayRawImage);
            app.ImageRowNum = row;
            app.ImageColumnNum = column;
            imgsize = strcat(int2str(row),'*',int2str(column));
            app.ImageSize.Value=imgsize;
            app.ImageMean.Value = num2str(mean2(app.GrayRawImage));
            app.StandardDeviation.Value=num2str(std2(app.GrayRawImage));

            %Do FFT conversion
            
            img = im2double(app.GrayRawImage);
            Fftimg = fft2(img);
            Fftimg = fftshift(Fftimg);
            Fftimg = abs(Fftimg);
            Fftimg = mat2gray(log(Fftimg+1));
            imshow(Fftimg,'parent',app.FFTAxes);
        end
        
        function results = LocalHistgramEqualization(app,masksize)

            I = app.GrayRawImage;
            [row, col]=size(I);
            detal = (masksize-1)/2;
            padded_img=padarray(I,[detal,detal],0,'both');
            results=zeros(row,col);
            for i=1:row
                for j=1:col
                    S_xy=padded_img(i:i+detal*2,j:j+detal*2);
                    eS_xy=histeq(S_xy);
                    results(i,j)=eS_xy(detal+1,detal+1);
                end
            end
            
        end
        
        function f = AlphaTrimMeanFilter(app,masksize)
            d=2;
            g=im2double(app.GrayRawImage);
            f = imfilter(g,ones(masksize,masksize),'symmetric');
            for k = 1:d/2
                f = imsubtract(f, ordfilt2(g,k,ones(masksize,masksize),'symmetric'));
            end
            for k = (masksize*masksize-(d/2)+1):masksize*masksize
                f = imsubtract(f, ordfilt2(g,k,ones(masksize,masksize),'symmetric'));
            end
            f = f/(masksize*masksize-d);
        end
        
        function g = mylocstat2(app,Icol,M,D,E,k)
            %MYLOCSTAT2 - perform single-back gray value 
            %	imadjustation based on local statistics for sliding block operation.
            % Motivation: try local statistics enhancement operation.
            % Parameters:
            %	Icol - the matrix includes the columns of all local neighbourhoods.
            %	M - the global mean of image's gray values.
            %	D - the global variance of image's gray values.
            %	E - the ehancement constant
            %	k - the 1x3 sized threshold vector:
            %		- k(1) is mean threshold
            %		- k(2) and k(3) are variance threshold
            
            %Localize the center of the each columnwise block.
            Bcenter=floor((size(Icol,1)+1)/2);
            g=Icol(Bcenter,:);
            %Preprocess the input parameters.
            if (nargin<5)
                  k=[0.4 0.02 0.4];
            end
            if (nargin<4)
                  E=4.0;
            end
            if (nargin<3)
                  disp('Mean and Variance must be provided!');
            end
            %Compute the local mean and variance.
            Mcol=mean(Icol);
            Dcol=std(Icol);
            %Build the local response.
            resind=find((Mcol<=k(1)*M) & (Dcol>=k(2)*D) & (Dcol<=k(3)*D));
            g(resind)=E*Icol(Bcenter,resind);
        end
        
        function g = mylocstat(app,Iloc,M,D,E,k)
            %Localize the center of the block.
            Bcenter=floor((size(Iloc)+1)/2);
            xc=Bcenter(1);yc=Bcenter(2);
            %Preprocess the input parameters.
            if (nargin<5)
                  k=[0.4 0.02 0.4];
            end
            if (nargin<4)
                  E=4.0;
            end
            if (nargin<3)
                  disp('Mean and Variance must be provided!');
            end
            %Compute the local mean and variance.
            Mloc=mean2(Iloc);
            Dloc=std2(Iloc);
            %Build the local response.
            if (Mloc<=k(1)*M) && (Dloc>=k(2)*D) && (Dloc<=k(3)*D)
                  g=E*Iloc(xc,yc);
            else
                  g=Iloc(xc,yc);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            
            
            imgpath='original.jpg';
            %Read the image data
            app.GrayRawImage255=imread(imgpath);
            %Change to gray image and store it to public variable
            if numel(size(app.GrayRawImage255))>2
                %Change to gray image and store it to public variable
                app.GrayRawImage255 = rgb2gray(app.GrayRawImage255);
            end
            app.GrayRawImage=mat2gray(app.GrayRawImage255);
            
            ShowImageBaseInfo(app);

        end

        % Menu selected function: LoadImageMenu
        function LoadImageMenuSelected(app, event)
           %Select a image file 
            [filename,pathname]=uigetfile( ...
            {'*.jpg;*.png;*.bmp;*.gif;*.tif', ...
                'image files (*.jpg,*.png,*.bmp,*.gif,*.tif)'; ...
                '*.*', 'All Files (*.*)'}, ...
                'Please select an image.');
            if isequal(filename,0) || isequal(pathname,0)
                return;
            else
                imgpath=[pathname,filename];
            end
            %Create axes graphics object
            %Read the image data
            app.GrayRawImage255=imread(imgpath);
            %Change to gray image and store it to public variable
            if numel(size(app.GrayRawImage255))>2
                %Change to gray image and store it to public variable
                app.GrayRawImage255 = rgb2gray(app.GrayRawImage255);
            end
            app.GrayRawImage=mat2gray(app.GrayRawImage255);
            
            ShowImageBaseInfo(app);
                        
        end

        % Button pushed function: ReduceGrayLevelButton
        function ReduceGrayLevelButtonPushed(app, event)

            if(app.ShowInFigure.Value == 0)
                imshow(ReduceGrayLevel(app,app.GrayRawImage255,1),'parent',app.GrayLevel255UIAxes);
                app.GrayLevel255UIAxes.Title.String ='256 Levles';
                imshow(ReduceGrayLevel(app,app.GrayRawImage255,2),'parent',app.GrayLevel128UIAxes);
                app.GrayLevel128UIAxes.Title.String ='128 Levels';
                imshow(ReduceGrayLevel(app,app.GrayRawImage255,3),'parent',app.GrayLevel64UIAxes);
                app.GrayLevel64UIAxes.Title.String ='64 Levels';
                imshow(ReduceGrayLevel(app,app.GrayRawImage255,4),'parent',app.GrayLevel32UIAxes);
                app.GrayLevel32UIAxes.Title.String ='32 Levels';
                imshow(ReduceGrayLevel(app,app.GrayRawImage255,5),'parent',app.GrayLevel16UIAxes);
                app.GrayLevel16UIAxes.Title.String ='16 Levels';
                imshow(ReduceGrayLevel(app,app.GrayRawImage255,6),'parent',app.GrayLevel8UIAxes);
                app.GrayLevel8UIAxes.Title.String ='8 Levels';
                imshow(ReduceGrayLevel(app,app.GrayRawImage255,7),'parent',app.GrayLevel4UIAxes);
                app.GrayLevel4UIAxes.Title.String ='4 Levels';
                imshow(ReduceGrayLevel(app,app.GrayRawImage255,8),'parent',app.GrayLevel2UIAxes);
                app.GrayLevel2UIAxes.Title.String ='2 Levels';
            elseif (app.ShowInFigure.Value == 1)
                subplot(2,4,1), imshow(ReduceGrayLevel(app,app.GrayRawImage255,1),[]), title('256 Levles');
                subplot(2,4,2), imshow(ReduceGrayLevel(app,app.GrayRawImage255,2),[]), title('128 Levles');
                subplot(2,4,3), imshow(ReduceGrayLevel(app,app.GrayRawImage255,3),[]), title('64 Levles');
                subplot(2,4,4), imshow(ReduceGrayLevel(app,app.GrayRawImage255,4),[]), title('32 Levles');
                subplot(2,4,5), imshow(ReduceGrayLevel(app,app.GrayRawImage255,5),[]), title('16 Levles');
                subplot(2,4,6), imshow(ReduceGrayLevel(app,app.GrayRawImage255,6),[]), title('8 Levles');
                subplot(2,4,7), imshow(ReduceGrayLevel(app,app.GrayRawImage255,7),[]), title('4 Levles');
                subplot(2,4,8), imshow(ReduceGrayLevel(app,app.GrayRawImage255,8),[]), title('2 Levles');
            end
            
        end

        % Value changed function: Piecewise_ax
        function Piecewise_axValueChanged(app, event)
            if (app.TransformationsButtonGroup.SelectedObject.Text == 'Piecewise-linear')
                PieceWiseLinearTransform(app);
            end
                                                 
        end

        % Value changed function: Piecewise_ay
        function Piecewise_ayValueChanged(app, event)
            if (app.TransformationsButtonGroup.SelectedObject.Text == 'Piecewise-linear')
                PieceWiseLinearTransform(app);
            end
                        
        end

        % Value changed function: Piecewise_bx
        function Piecewise_bxValueChanged(app, event)
            if (app.TransformationsButtonGroup.SelectedObject.Text == 'Piecewise-linear')
                PieceWiseLinearTransform(app);
            end
            
        end

        % Value changed function: Piecewise_by
        function Piecewise_byValueChanged(app, event)
            if (app.TransformationsButtonGroup.SelectedObject.Text == 'Piecewise-linear')
                PieceWiseLinearTransform(app);
            end
            
        end

        % Value changed function: ResizeRatioEditField
        function ResizeRatioEditFieldValueChanged(app, event)
            value = app.ResizeRatioEditField.Value;
            
            imshow(app.GrayRawImage, 'parent',app.ResizeUIAxes3);
            [m,n] = size(app.GrayRawImage);
            app.ResizeUIAxes3.Title.String = strcat('Original-','Size:',num2str(n),'X',num2str(m));
            
            sized_img1 = imresize(im2double(app.GrayRawImage),value,'Method',"nearest");
            
            imshow(sized_img1, 'parent',app.ResizeUIAxes0);
            [m,n] = size(sized_img1);
            app.ResizeUIAxes0.Title.String = strcat('Nearest-','Size:',num2str(n),'X',num2str(m));
            
            sized_img2 = imresize(im2double(app.GrayRawImage),value,'Method',"bilinear");
            imshow(sized_img2, 'parent',app.ResizeUIAxes1);
            [m,n] = size(sized_img2);
            app.ResizeUIAxes1.Title.String = strcat('Bilinear-','Size:',num2str(n),'X',num2str(m));
            
            sized_img3 = imresize(im2double(app.GrayRawImage),value,'Method',"bicubic");
            imshow(sized_img3, 'parent',app.ResizeUIAxes2);
            [m,n] = size(sized_img3);
            app.ResizeUIAxes2.Title.String = strcat('Bicubic-','Size:',num2str(n),'X',num2str(m));

        end

        % Button pushed function: SDFilterButton
        function SDFilterButtonPushed(app, event)
            [SelectionNum, SelectedFunc] = CheckSDFilteringFuncSelection(app);
            
            if (SelectionNum == 1)
                switch SelectedFunc
                    case 'Average'
                        h1=fspecial('average',[app.AverageMask1.Value,app.AverageMask1.Value]);
                        h2=fspecial('average',[app.AverageMask2.Value,app.AverageMask2.Value]);
                        h3=fspecial('average',[app.AverageMask3.Value,app.AverageMask3.Value]);
                        g1=imfilter(app.GrayRawImage,h1);
                        g2=imfilter(app.GrayRawImage,h2);
                        g3=imfilter(app.GrayRawImage,h3);
                        
                        title1 = strcat(num2str(app.AverageMask1.Value),'X',num2str(app.AverageMask1.Value),' mean filter mask');
                        title2 = strcat(num2str(app.AverageMask2.Value),'X',num2str(app.AverageMask2.Value),' mean filter mask');
                        title3 = strcat(num2str(app.AverageMask3.Value),'X',num2str(app.AverageMask3.Value),' mean filter mask');
    
                    case 'Median'
                        g1 = medfilt2(app.GrayRawImage, [app.MedianMask1.Value,app.MedianMask1.Value]);
                        g2 = medfilt2(app.GrayRawImage, [app.MedianMask2.Value,app.MedianMask2.Value]);
                        g3 = medfilt2(app.GrayRawImage, [app.MedianMask3.Value,app.MedianMask3.Value]);
                        title1 = strcat(num2str(app.MedianMask1.Value),'X',num2str(app.MedianMask1.Value),' Median filter mask');
                        title2 = strcat(num2str(app.MedianMask2.Value),'X',num2str(app.MedianMask2.Value),' Median filter mask');
                        title3 = strcat(num2str(app.MedianMask3.Value),'X',num2str(app.MedianMask3.Value),' Median filter mask');
                        
                    case 'Gaussian'
                        kernalsize=app.SD_Gaussian_mask.Value;
                        g1 = imgaussfilt(app.GrayRawImage,app.GaussianSigma1.Value,'FilterSize',kernalsize,'FilterDomain','spatial');
                        g2 = imgaussfilt(app.GrayRawImage,app.GaussianSigma2.Value,'FilterSize',kernalsize,'FilterDomain','spatial');
                        g3 = imgaussfilt(app.GrayRawImage,app.GaussianSigma3.Value,'FilterSize',kernalsize,'FilterDomain','spatial');
                        title1 = strcat('Gaussian sigma ',num2str(app.GaussianSigma1.Value),' Kernal ',num2str(app.SD_Gaussian_mask.Value));
                        title2 = strcat('Gaussian sigma ',num2str(app.GaussianSigma2.Value),' Kernal ',num2str(app.SD_Gaussian_mask.Value));
                        title3 = strcat('Gaussian sigma ',num2str(app.GaussianSigma3.Value),' Kernal ',num2str(app.SD_Gaussian_mask.Value));
                       
                    case 'Laplacian'
                        kernal=fspecial('laplacian',app.LaplacianAlpha.Value);
                        %kernal=[-1,0,1;-2,0,2;-1,0,1];
                        g1=imfilter(app.GrayRawImage,kernal);

                        g2=app.GrayRawImage + app.Laplacian_k.Value .* g1; 
                        
                        g3 = [];

                        title1 = strcat('b:Laplacian alpha ',num2str(app.LaplacianAlpha.Value));
                        title2 = strcat('c:a+k*b ');
                        title3 = '';
                   
                    case 'Sobel'
                        kernal = fspecial('sobel');
                        %kernal=[-1,0,1;-2,0,2;-1,0,1];

                        g1 = imfilter(app.GrayRawImage,kernal);
                        g2 = imfilter(app.GrayRawImage,transpose(kernal));
                        g3 = g1+g2;
                        title1 = 'b:Sobel filtering-H';
                        title2 = 'c:Sobel filtering-V';
                        title3 = strcat('c:a+*b ');
                        
                    case 'Prewitt'
                        h1 = fspecial('prewitt');
                        g1 = imfilter(app.GrayRawImage,h1);
                        g2 = app.GrayRawImage + app.Sobel_k.Value .* g1;
                        g3 = [];
                        title1 = 'b:Prewitt filtering';
                        title2 = strcat('c:a+k*b ');
                        title3 = '';
                    
                    case 'ContraHarm'
                        
                        g1 = ContraHarmFiltering(app, app.GrayRawImage, app.ContraharmQ.Value, app.ContraharmMask1.Value);
                        g2 = ContraHarmFiltering(app, app.GrayRawImage, app.ContraharmQ.Value, app.ContraharmMask2.Value);
                        g3 = ContraHarmFiltering(app, app.GrayRawImage, app.ContraharmQ.Value, app.ContraharmMask3.Value);
                        title1 = strcat(num2str(app.ContraharmMask1.Value),'X',num2str(app.ContraharmMask1.Value),' ContraHarm filter mask');
                        title2 = strcat(num2str(app.ContraharmMask2.Value),'X',num2str(app.ContraharmMask2.Value),' ContraHarm filter mask');
                        title3 = strcat(num2str(app.ContraharmMask3.Value),'X',num2str(app.ContraharmMask3.Value),' ContraHarm filter mask');
                    otherwise
                        
                end
                app.SDFilteredImg1 = g1;
                app.SDFilteredImg2 = g2;
                app.SDFilteredImg3 = g3;
                %Display filtered images
                imshow(app.GrayRawImage,'parent',app.SDFilteringUIAxes0);
                app.SDFilteringUIAxes0.Title.String = 'a:Original';
                
                imshow(g1,'parent',app.SDFilteringUIAxes1);
                app.SDFilteringUIAxes1.Title.String = title1;
                if(size(g2) ~= 0)
                    imshow(g2,'parent',app.SDFilteringUIAxes2);
                else
                    cla(app.SDFilteringUIAxes2,'reset');
                end
                app.SDFilteringUIAxes2.Title.String = title2;
                if(size(g3) ~= 0)
                    imshow(g3,'parent',app.SDFilteringUIAxes3);
                else
                    cla(app.SDFilteringUIAxes3,'reset');
                end
                app.SDFilteringUIAxes3.Title.String = title3;
                
                app.ProcessedImg=g1;
                
            elseif(SelectionNum > 1)
                img_index = 1;
                if(app.AverageCheckBox.Value == 1)
                    subplot(SelectionNum,4,img_index), imshow(app.GrayRawImage,[]),title('Original');
                    img_index = img_index + 1;
                    
                    h1=fspecial('average',[app.AverageMask1.Value,app.AverageMask1.Value]);
                    h2=fspecial('average',[app.AverageMask2.Value,app.AverageMask2.Value]);
                    h3=fspecial('average',[app.AverageMask3.Value,app.AverageMask3.Value]);
                    g1=imfilter(app.GrayRawImage,h1);
                    g2=imfilter(app.GrayRawImage,h2);
                    g3=imfilter(app.GrayRawImage,h3);
                    
                    title1 = strcat(num2str(app.AverageMask1.Value),'X',num2str(app.AverageMask1.Value),' mean filter mask');
                    subplot(SelectionNum,4,img_index), imshow(g1,[]),title(title1);
                    img_index = img_index + 1;
                    
                    title2 = strcat(num2str(app.AverageMask2.Value),'X',num2str(app.AverageMask2.Value),' mean filter mask');
                    subplot(SelectionNum,4,img_index), imshow(g2,[]),title(title2);
                    img_index = img_index + 1;
                    
                    title3 = strcat(num2str(app.AverageMask3.Value),'X',num2str(app.AverageMask3.Value),' mean filter mask');
                    subplot(SelectionNum,4,img_index), imshow(g3,[]),title(title3);
                    img_index = img_index + 1;
                end
                
                if(app.MedianCheckBox.Value == 1)
                    subplot(SelectionNum,4,img_index), imshow(app.GrayRawImage,[]),title('Original');
                    img_index = img_index + 1;
                    
                    g1 = medfilt2(app.GrayRawImage, [app.MedianMask1.Value,app.MedianMask1.Value]);
                    g2 = medfilt2(app.GrayRawImage, [app.MedianMask2.Value,app.MedianMask2.Value]);
                    g3 = medfilt2(app.GrayRawImage, [app.MedianMask3.Value,app.MedianMask3.Value]);
                    title1 = strcat(num2str(app.MedianMask1.Value),'X',num2str(app.MedianMask1.Value),' Median filter mask');
                    title2 = strcat(num2str(app.MedianMask2.Value),'X',num2str(app.MedianMask2.Value),' Median filter mask');
                    title3 = strcat(num2str(app.MedianMask3.Value),'X',num2str(app.MedianMask3.Value),' Median filter mask');

                    subplot(SelectionNum,4,img_index), imshow(g1,[]),title(title1);
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(g2,[]),title(title2);
                    img_index = img_index + 1;

                    subplot(SelectionNum,4,img_index), imshow(g3,[]),title(title3);
                    img_index = img_index + 1;
                end
                
                if(app.GaussianCheckBox.Value == 1)
                    subplot(SelectionNum,4,img_index), imshow(app.GrayRawImage,[]),title('Original');
                    img_index = img_index + 1;
                    
                    g1 = imgaussfilt(app.GrayRawImage,app.GaussianSigma1.Value);
                    g2 = imgaussfilt(app.GrayRawImage,app.GaussianSigma2.Value);
                    g3 = imgaussfilt(app.GrayRawImage,app.GaussianSigma3.Value);
                    title1 = strcat('Gaussian sigma ',num2str(app.GaussianSigma1.Value));
                    title2 = strcat('Gaussian sigma ',num2str(app.GaussianSigma2.Value));
                    title3 = strcat('Gaussian sigma ',num2str(app.GaussianSigma3.Value));

                    subplot(SelectionNum,4,img_index), imshow(g1,[]),title(title1);
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(g2,[]),title(title2);
                    img_index = img_index + 1;

                    subplot(SelectionNum,4,img_index), imshow(g3,[]),title(title3);
                    img_index = img_index + 1;
                end
                
                if(app.LaplacianCheckBox.Value == 1)
                    subplot(SelectionNum,4,img_index), imshow(app.GrayRawImage,[]),title('Original');
                    img_index = img_index + 1;
                    
                    h1=fspecial('laplacian',app.LaplacianAlpha.Value);
                    %h2=fspecial('laplacian',0.2);
                    %h3=fspecial('laplacian',0.5);
                    g1=imfilter(app.GrayRawImage,h1);
                    g2=[];
                    g3=[];
                    %title1 = strcat('Laplacian alpha ',num2str(app.LaplacianAlpha.Value));
                    %title2 = strcat('Laplacian alpha ',num2str(app.LaplacianAlpha2.Value));
                    %title3 = strcat('Laplacian alpha ',num2str(app.LaplacianAlpha3.Value));

                    subplot(SelectionNum,4,img_index), imshow(g1,[]),title(title1);
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(g2,[]),title(title2);
                    img_index = img_index + 1;

                    subplot(SelectionNum,4,img_index), imshow(g3,[]),title(title3);
                    img_index = img_index + 1;
                end
                
                if(app.SobelCheckBox.Value == 1)
                    subplot(SelectionNum,4,img_index), imshow(app.GrayRawImage,[]),title('Original');
                    img_index = img_index + 1;
                    
                    h1 = fspecial('sobel');
                    g1 = imfilter(app.GrayRawImage,h1);
                    g2 = [];
                    g3 = [];
                    title1 = 'Sobel filtering';
                    title2 = '';
                    title3 = '';

                    subplot(SelectionNum,4,img_index), imshow(g1,[]),title(title1);
                    img_index = img_index + 1;
                    
                    %subplot(SelectionNum,4,img_index), imshow(g2,[]),title(title2);
                    img_index = img_index + 1;

                    %subplot(SelectionNum,4,img_index), imshow(g3,[]),title(title3);
                    img_index = img_index + 1;
                end
                if(app.PrewittCheckBox.Value == 1)
                    subplot(SelectionNum,4,img_index), imshow(app.GrayRawImage,[]),title('Original');
                    img_index = img_index + 1;
                    
                    h1 = fspecial('prewitt');
                    g1 = imfilter(app.GrayRawImage,h1);
                    g2 = [];
                    g3 = [];
                    title1 = 'Prewitt filtering';
                    title2 = '';
                    title3 = '';

                    subplot(SelectionNum,4,img_index), imshow(g1,[]),title(title1);
                    img_index = img_index + 1;
                    
                    %subplot(SelectionNum,4,img_index), imshow(g2,[]),title(title2);
                    img_index = img_index + 1;

                    %subplot(SelectionNum,4,img_index), imshow(g3,[]),title(title3);
                    img_index = img_index + 1;
                end
                if(app.ContraharmCheckBox.Value == 1)
                    subplot(SelectionNum,4,img_index), imshow(app.GrayRawImage,[]),title('Original');
                    img_index = img_index + 1;
                    
                    g1 = ContraHarmFiltering(app, app.GrayRawImage, app.ContraharmQ.Value, app.ContraharmMask1.Value);
                    g2 = ContraHarmFiltering(app, app.GrayRawImage, app.ContraharmQ.Value, app.ContraharmMask2.Value);
                    g3 = ContraHarmFiltering(app, app.GrayRawImage, app.ContraharmQ.Value, app.ContraharmMask3.Value);
                    title1 = strcat(num2str(app.ContraharmMask1.Value),'X',num2str(app.ContraharmMask1.Value),' ContraHarm filter mask');
                    title2 = strcat(num2str(app.ContraharmMask2.Value),'X',num2str(app.ContraharmMask2.Value),' ContraHarm filter mask');
                    title3 = strcat(num2str(app.ContraharmMask3.Value),'X',num2str(app.ContraharmMask3.Value),' ContraHarm filter mask');

                    subplot(SelectionNum,4,img_index), imshow(g1,[]),title(title1);
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(g2,[]),title(title2);
                    img_index = img_index + 1;

                    subplot(SelectionNum,4,img_index), imshow(g3,[]),title(title3);
                    img_index = img_index + 1;
                end
                if(app.AlphaTrimMeanCheckBox.Value ==1)
                    subplot(SelectionNum,4,img_index), imshow(app.GrayRawImage,[]),title('Original');
                    img_index = img_index + 1;
                    
                    g1 = AlphaTrimMeanFilter(app,app.AlphaTrim_mask.Value);
                    g2 =[];
                    g3= [];
                    title1 = 'b:Alpha Trimmed filtering';
                    title2 = '';
                    title3 = '';

                    subplot(SelectionNum,4,img_index), imshow(g1,[]),title(title1);
                    img_index = img_index + 1;
                    
                    %subplot(SelectionNum,4,img_index), imshow(g2,[]),title(title2);
                    img_index = img_index + 1;

                    %subplot(SelectionNum,4,img_index), imshow(g3,[]),title(title3);
                    img_index = img_index + 1;
                end
                
            end
                
        end

        % Button pushed function: FDFilterButton
        function FDFilterButtonPushed(app, event)
            f=im2double(app.GrayRawImage);
            
            %计算填充图像大小
            [M,N]=size(f);
            P=2*M;
            Q=2*N;
            
            %傅里叶变换
            F=fftshift(fft2(f,P,Q));
            fft_img=mat2gray(log(1+abs(F)));
            
            %生成网格坐标
            u = -N:N-1;
            v = -M:M-1;
            [U,V] = meshgrid(u,v);
            
            %设计滤波器
            D = hypot(U,V);

            [SelectionNum, SelectedFunc] = CheckFDFilteringFuncSelection(app);
            
            if (SelectionNum == 1)
                switch SelectedFunc
                    case 'IdealLowPass'
                        D0 = app.IdealLowPassD0.Value; %截止频率
                        
                        H = mat2gray(D <= D0); %理想低通滤波器
                        title_H = 'IdealLowPassFilter';
                        title_g = strcat('After IdealLowPassFiltering:D0=',num2str(D0));
                    case 'GaussianLowPass'
                        D0 = app.GaussianLowPassD0.Value; %截止频率
                        
                        H = exp((-D.^2)/(2*(D0).^2));%高斯低通滤波器
                        title_H = 'GaussianLowPassFilter';
                        title_g = strcat('After GaussianLowPassFiltering:D0=',num2str(D0));
     
                    case 'ButterworthLowPass'
                        D0 = app.ButterworthLowPassD0.Value; %截止频率
                        n = app.ButterworthLowPassn.Value; %巴特沃斯滤波器阶数
                        
                        H = mat2gray(1./(1+((D./D0).^(2*n)))); %巴特沃斯低通滤波器
                        title_H = 'ButterworthLowPassFilter';
                        title_g = strcat('After ButterworthLowPassFiltering:D0=',num2str(D0));
                        
                    case 'IdealHighPass'
                        D0 = app.IdealHighPassD0.Value; %截止频率
                        
                        H = mat2gray(D > D0); %理想高通滤波器
                        title_H = 'IdealHighPassFilter';
                        title_g = strcat('After IdealHighPassFiltering:D0=',num2str(D0));
                        
                    case 'GaussianHighPass'
                        D0 = app.GaussianHighPassD0.Value; %截止频率
                        
                        H = 1-exp((-D.^2)/(2*(D0).^2));%高斯高通滤波器
                        title_H = 'GaussianHighPassFilter';
                        title_g = strcat('After GaussianHighPassFiltering:D0=',num2str(D0));
                        
                    case 'ButterworthHighPass'
                        D0 = app.ButterworthHighPassD0.Value; %截止频率
                        n = app.ButterworthHighPassn.Value; %巴特沃斯滤波器阶数
                        
                        H = 1./(1+((D0./D).^(2*n)));%布特沃斯高通滤波器
                        title_H = 'ButterworthHighPassFilter';
                        title_g = strcat('After ButterworthHighPassFiltering:D0=',num2str(D0));
                    case 'ButterworthNotchFilter'
                        H = ButterworthNotchFilter(app);
                        title_H = 'ButterworthNotchFilter';
                        title_g = 'After ButterworthNotchFiltering';
                    otherwise
                        
                end
                %频域滤波
                G = F.*H;
                g0 = ifft2(fftshift(G));
                g = g0(1:M,1:N);
                g = real(g);
                
                imshow(f,'parent',app.FDFilteringUIAxes0);
                app.FDFilteringUIAxes0.Title.String = 'original';
                
                imshow(fft_img,'parent',app.FDFilteringUIAxes1);
                app.FDFilteringUIAxes1.Title.String = 'FFT';
                
                imshow(H,'parent',app.FDFilteringUIAxes2);
                app.FDFilteringUIAxes2.Title.String = title_H;
                
                imshow(g,'parent',app.FDFilteringUIAxes3);
                app.FDFilteringUIAxes3.Title.String = title_g;
                
                app.FDFilteredImg=g;
            elseif (SelectionNum > 1)
                img_index = 1;
                if(app.IdealLowPassCheckBox.Value == 1)
                    D0 = app.IdealLowPassD0.Value; %截止频率
                    
                    H = mat2gray(D <= D0); %理想低通滤波器
                    title_H = 'IdealLowPassFilter';
                    title_g = 'After IdealLowPassFiltering';
                    %频域滤波
                    G = F.*H;
                    g0 = ifft2(fftshift(G));
                    g = g0(1:M,1:N);
                    g = real(g);
                    
                    subplot(SelectionNum,4,img_index), imshow(f,[]),title('original');
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(fft_img,[]),title('FFT');
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(H,[]),title(title_H);
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(g,[]),title(title_g);
                    img_index = img_index + 1;
                    
                end
                if(app.GaussianLowPassCheckBox.Value == 1)
                    D0 = app.GaussianLowPassD0.Value; %截止频率
                    
                    H = exp((-D.^2)/(2*(D0).^2));%高斯低通滤波器
                    title_H = 'GaussianLowPassFilter';
                    title_g = 'After GaussianLowPassFiltering';
                    %频域滤波
                    G = F.*H;
                    g0 = ifft2(fftshift(G));
                    g = g0(1:M,1:N);
                    g = real(g);
                    
                    subplot(SelectionNum,4,img_index), imshow(f,[]),title('original');
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(fft_img,[]),title('FFT');
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(H,[]),title(title_H);
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(g,[]),title(title_g);
                    img_index = img_index + 1;
                    
                end
                if(app.ButterworthLowPassCheckBox.Value == 1)
                    D0 = app.ButterworthLowPassD0.Value; %截止频率
                    n = app.ButterworthLowPassn.Value; %巴特沃斯滤波器阶数
                    
                    H = mat2gray(1./(1+((D./D0).^(2*n)))); %巴特沃斯低通滤波器
                    title_H = 'ButterworthLowPassFilter';
                    title_g = 'After ButterworthLowPassFiltering';
                    %频域滤波
                    G = F.*H;
                    g0 = ifft2(fftshift(G));
                    g = g0(1:M,1:N);
                    g = real(g);
                    
                    subplot(SelectionNum,4,img_index), imshow(f,[]),title('original');
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(fft_img,[]),title('FFT');
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(H,[]),title(title_H);
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(g,[]),title(title_g);
                    img_index = img_index + 1;
                    
                end
                if(app.ButterworthLowPassCheckBox.Value == 1)
                    H = ButterworthNotchFilter(app);
                    title_H = 'ButterworthNotchFilter';
                    title_g = 'After ButterworthNotchFiltering';
                    %频域滤波
                    G = F.*H;
                    g0 = ifft2(fftshift(G));
                    g = g0(1:M,1:N);
                    g = real(g);
                    
                    subplot(SelectionNum,4,img_index), imshow(f,[]),title('original');
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(fft_img,[]),title('FFT');
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(H,[]),title(title_H);
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(g,[]),title(title_g);
                    img_index = img_index + 1;
                    
                end
                if(app.IdealHighPassCheckBox.Value == 1)
                    D0 = app.IdealHighPassD0.Value; %截止频率
                    
                    H = mat2gray(D > D0); %理想高通滤波器
                    title_H = 'IdealHighPassFilter';
                    title_g = 'After IdealHighPassFiltering';
                    %频域滤波
                    G = F.*H;
                    g0 = ifft2(fftshift(G));
                    g = g0(1:M,1:N);
                    g = real(g);
                    
                    subplot(SelectionNum,4,img_index), imshow(f,[]),title('original');
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(fft_img,[]),title('FFT');
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(H,[]),title(title_H);
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(g,[]),title(title_g);
                    img_index = img_index + 1;
                    
                end
                if(app.GaussianHighPassCheckBox.Value == 1)
                    D0 = app.GaussianHighPassD0.Value; %截止频率
                    
                    H = 1-exp((-D.^2)/(2*(D0).^2));%高斯高通滤波器
                    title_H = 'GaussianHighPassFilter';
                    title_g = 'After GaussianHighPassFiltering';
                    %频域滤波
                    G = F.*H;
                    g0 = ifft2(fftshift(G));
                    g = g0(1:M,1:N);
                    g = real(g);
                    
                    subplot(SelectionNum,4,img_index), imshow(f,[]),title('original');
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(fft_img,[]),title('FFT');
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(H,[]),title(title_H);
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(g,[]),title(title_g);
                    img_index = img_index + 1;
                    
                end
                if(app.ButterworthHighPassCheckBox.Value == 1)
                    D0 = app.ButterworthHighPassD0.Value; %截止频率
                    n = app.ButterworthHighPassn.Value; %巴特沃斯滤波器阶数
                    
                    H = 1./(1+((D0./D).^(2*n)));%布特沃斯高通滤波器
                    title_H = 'ButterworthHighPassFilter';
                    title_g = 'After ButterworthHighPassFiltering';
                    %频域滤波
                    G = F.*H;
                    g0 = ifft2(fftshift(G));
                    g = g0(1:M,1:N);
                    g = real(g);
                    
                    subplot(SelectionNum,4,img_index), imshow(f,[]),title('original');
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(fft_img,[]),title('FFT');
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(H,[]),title(title_H);
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(g,[]),title(title_g);
                    img_index = img_index + 1;
                    
                end
            end


        end

        % Button pushed function: NoiseReduceButton
        function NoiseReduceButtonPushed(app, event)
            [NoiseFuncNum, SelectedNoiseFunc] = CheckNoiseAdditionFunction(app);
            noise = zeros(app.ImageRowNum,app.ImageColumnNum);
            if(NoiseFuncNum == 1)
                switch SelectedNoiseFunc
                    case 'GaussianNoise'
                        noise = imnoise2(app,"gaussian",app.ImageRowNum,app.ImageColumnNum,app.GaussianNoisemean.Value,app.GaussianNoiseVar.Value);
           
                    case 'SaltpepperNoise'
                        noise = imnoise2(app,"salt & pepper",app.ImageRowNum,app.ImageColumnNum,app.SaltPepperPa.Value,app.SaltPepperPa.Value);

                    case 'RayleighNoise'
                        noise = imnoise2(app,"rayleigh",app.ImageRowNum,app.ImageColumnNum,app.RayleighNoiseA.Value,app.RayleighNoiseB.Value);

                    case 'ExponentialNoise'
                        noise = imnoise2(app,"exponential",app.ImageRowNum,app.ImageColumnNum,app.ExponentialNoiseA.Value);

                    case 'ErlangNoise'
                        noise = imnoise2(app,"erlang",app.ImageRowNum,app.ImageColumnNum,app.ErlangNoiseA.Value,app.ErlangNoiseB.Value);

                    case 'LognormalNoise'
                        %noise = imnoise2(app,"lognormal",app.ImageRowNum,app.ImageColumnNum,app.ErlangNoiseA.Value,app.ErlangNoiseB);

                    otherwise
                end
                switch SelectedNoiseFunc
                    case 'SaltpepperNoise'
                        noised_img = noise;
                    otherwise 
                        noised_img = app.GrayRawImage + noise;
                end
                
            else
                noised_img = app.GrayRawImage;
                
            end
            
            [SelectionNum, SelectedFunc] = CheckNoiseReductionFuncSelection(app);
            if (SelectionNum == 1)
                switch SelectedFunc
                    case 'Arithmetic'
                        h1=fspecial('average',[app.ArithmeticMask1.Value,app.ArithmeticMask1.Value]);
                        f1=imfilter(noised_img,h1);
 
                    case 'Geometric'
                        f1 = GeometricMeanFiltering(app,noised_img,app.GeometricMask1.Value);
                        
                    case 'Contraharmonic'
                        f1 = ContraHarmFiltering(app, noised_img, app.ContraHarmonicQ.Value, app.ContraHarmonicMask1.Value);

                    case 'OrderStatisticsfilters'
                        order = app.OrderStatisticsOrder.Value;
                        masksize=app.OrderStatisticsMask.Value;
                        kernal=ones(masksize, masksize);
                        f1 = ordfilt2(noised_img, order, kernal);
                    case 'Adaptivefilters'
                        f1 = AdaptiveMedianFiltering(app,noised_img);
                    case 'WienerFilter'
                        f1 = wiener2(app.GrayRawImage,[app.WienerFilter_mask.Value,app.WienerFilter_mask.Value]);
                    case 'AlphaTrimMean'
                        f1 = AlphaTrimMeanFilter(app,app.AlphaTrimMean_mask.Value);
                    otherwise    
                end
                imshow(app.GrayRawImage,'parent',app.NoiseReductionUIAxes0);
                app.NoiseReductionUIAxes0.Title.String = 'original';
                
                imshow(noise,'parent',app.NoiseReductionUIAxes1);
                app.NoiseReductionUIAxes1.Title.String = strcat(SelectedNoiseFunc,' noise');
                
                imshow(noised_img,'parent',app.NoiseReductionUIAxes2);
                app.NoiseReductionUIAxes2.Title.String = strcat('With ',SelectedNoiseFunc,' noise');
                
                imshow(f1,'parent',app.NoiseReductionUIAxes3);
                app.NoiseReductionUIAxes3.Title.String = strcat("After ",SelectedFunc,' filter');
                
                app.ProcessedImg=f1;
                
            elseif(SelectionNum > 1)
                img_index = 1;
                
                 subplot(2,SelectionNum,img_index), imshow(app.GrayRawImage,[]),title('original');
                 img_index = img_index + 1;
                 
                 subplot(2,SelectionNum,img_index), imshow(noise,[]),title(strcat(SelectedNoiseFunc,' noise'));
                 img_index = img_index + 1;
                 
                 %skip 1st row next 3 slot
                 img_index = img_index + SelectionNum - 2;

                if(app.ArithmeticCheckBox.Value == 1)
                    SelectedFunc = 'Arithmetic';
                    h1=fspecial('average',[app.ArithmeticMask1.Value,app.ArithmeticMask1.Value]);
                    f1=imfilter(noised_img,h1);
                    
                    subplot(2,SelectionNum,img_index), imshow(f1,[]),title(strcat('After ', SelectedFunc, ' Filtering'));
                    img_index = img_index + 1;
                    
                end
                
                if (app.GeometricCheckBox.Value == 1)
                    SelectedFunc = 'Geometric';
                    f1 = GeometricMeanFiltering(app,noised_img,app.GeometricMask1.Value, app.GeometricMask1.Value);
                    
                    subplot(2,SelectionNum,img_index), imshow(f1,[]),title(strcat('After ', SelectedFunc, ' Filtering'));
                    img_index = img_index + 1;
                end
                
                if (app.ContraharmonicCheckBox.Value == 1)
                    SelectedFunc = 'Contraharmonic';
                    f1 = ContraHarmFiltering(app, noised_img, app.ContraHarmonicQ.Value, app.ContraHarmonicMask1.Value);
                    
                    subplot(2,SelectionNum,img_index), imshow(f1,[]),title(strcat('After ', SelectedFunc, ' Filtering'));
                    img_index = img_index + 1;
                end
                
                if (app.OrderStatisticsCheckBox.Value == 1)
                    SelectedFunc = 'OrderStatisticsfilters';
                    f1 = ordfilt2(noised_img, app.OrderStatisticsOrder.Value, ones(app.OrderStatisticsMask.Value, app.OrderStatisticsMask.Value));
                    
                    subplot(2,SelectionNum,img_index), imshow(f1,[]),title(strcat('After ', SelectedFunc, ' Filtering'));
                    img_index = img_index + 1;
                end
                
                if (app.AdaptivemeanfiltersCheckBox.Value == 1)
                    SelectedFunc = 'Adaptivefilters'; 
                    
                    f1 = AdaptiveMedianFiltering(app,noised_img);
                    
                    subplot(2,SelectionNum,img_index), imshow(f1,[]),title(strcat('After ', SelectedFunc, ' Filtering'));
                    img_index = img_index + 1;
                end
                if (app.WienerFilterCheckBox.Value == 1)
                    SelectedFunc = 'WienerFilter'; 
                    
                     f1 = wiener2(app.GrayRawImage,[app.WienerFilter_mask.Value,app.WienerFilter_mask.Value]);
                    
                    subplot(2,SelectionNum,img_index), imshow(f1,[]),title(strcat('After ', SelectedFunc, ' Filtering'));
                    img_index = img_index + 1;
                end
                if(app.AlphaTrimMeanCheckBox.Value ==1)
                    SelectedFunc = 'AlphaTrimMeanFilter'; 
                    
                    f1 = AlphaTrimMeanFilter(app,app.AlphaTrim_mask.Value);
                    
                    subplot(2,SelectionNum,img_index), imshow(f1,[]),title(strcat('After ', SelectedFunc, ' Filtering'));
                    img_index = img_index + 1;

                end
            end

        end

        % Button pushed function: TransformButton
        function TransformButtonPushed(app, event)
            value = app.TransformationsButtonGroup.SelectedObject.Text;
            imshow(app.GrayRawImage,'parent',app.OriginalUIAxes);
            histogram(app.OriginalHistUIAxes, app.GrayRawImage,255);
            result_title='';
            s=[];
            switch value
                case 'Negative'
   
                    x= 0:0.01:1;
                    y= 1-x;
                    
                    plot(app.TransformationUIAxes,y,'g');
                    title(app.TransformationUIAxes,'Negative transformation');
                    
                    s=1-app.GrayRawImage;

                    result_title = "Negative transformation: s=L-1-r";
                    
                case 'Logrithm'
                    c=app.LogPara_c.Value;
                    v=app.LogPara_v.Value;
                    
                    x = 0:0.01:1;

                    y = c*log2(1 + v*x)/log2(v+1); 
                    
                    plot(app.TransformationUIAxes,x,y,'g');
                    
                    title(app.TransformationUIAxes,'Log transformation: s = c*log2(1+v*r)/log2(1+v)');
            
                    lgd = legend(app.TransformationUIAxes,strcat('Log:v=',num2str(v)));
                    legend(app.TransformationUIAxes,'Location','southeast');

                    s=LogTransform(app,c,v);
                    
                    result_title=strcat("Log Transformation:v=",num2str(v));
                    
                case 'PowerLaw'
                    
                    C = 1;
                    
                    f = 0:0.01:1;
                    Gamma = app.PowerLawY.Value;
                    y1 = C*(f.^Gamma); 
                    
                    plot(app.TransformationUIAxes,f,y1,'g');   
                    
                    title(app.TransformationUIAxes,'PowerLaw transformation: g = r^γ');
                    
                    lgd = legend(app.TransformationUIAxes,strcat('Gamma=',num2str(Gamma)));
                    legend(app.TransformationUIAxes,'Location','southeast');
                    
                    s=PowerLawTransform(app);
                    result_title=strcat('PowerLaw: ',"γ = ",num2str(Gamma));
                    
                case 'Piecewise-linear'
                    title(app.TransformationUIAxes,'Piecewise-linear transformation');
                    s=PieceWiseLinearTransform(app);
                    result_title='Piecewise-Linear';
                    
                case 'Histogram Equalization'
                    title(app.TransformationUIAxes,'');
                    s=histeq(app.GrayRawImage);
                    result_title='Histogram Equalization';

                case 'Local Histogram Equalization'
                    title(app.TransformationUIAxes,'');
                    masksize = app.LocalHE_Masksize.Value;
                    
                    s=LocalHistgramEqualization(app,masksize);
                    result_title='Local Histogram Equalization';
                case 'Local Histogram Statistics'
                    title(app.TransformationUIAxes,'');
                    k0=app.LHS_k0.Value;
                    k1=app.LHS_k1.Value;
                    k2=app.LHS_k2.Value;
                    E=app.LHS_E.Value;
                    masksize = app.LocalHE_Masksize.Value; % 邻域范围，奇数
                    
                    Isem=app.GrayRawImage;
                    %% Caculate the global mean and variance.
                    f=double(Isem);
                    M=mean2(f);D=std2(f);
                    %% Perform the enhancing task.
                    Bsize=[masksize masksize];
                    k=[k0 k1 k2];
      
                    s=colfilt(f,Bsize,'sliding', @mylocstat2,M,D,E,k);
                    result_title='Local Histogram Statistics';
                    
                case 'Intensity-Level Slicing'
                    I=app.GrayRawImage;
                    ax=app.IntensityLevelSlicing_ax.Value;
                    ay=app.IntensityLevelSlicing_ay.Value;
                    bx=app.IntensityLevelSlicing_bx.Value;
                    by=app.IntensityLevelSlicing_by.Value;
                    L1=app.IntensityLevelSlicing_L1.Value;
                    L2=app.IntensityLevelSlicing_L2.Value;
                    
                    x = 0:0.001:1;
                    y = x;
                    for a=1:1:1001
                        if((x(a)>=ax) && (x(a)<=ay))
                            y(a)=L1;
                        elseif((x(a)>=bx) && (x(a)<=by))
                            y(a)=L2;
                        end
                    end
                    plot(app.TransformationUIAxes,x,y,'g');  
                    title(app.TransformationUIAxes,'Intensity-Level Slicing');
                    for i=1:size(I,1)
                        for j=1:size(I,2)
                            if((I(i,j)>=ax) && (I(i,j)<=ay))
                                I(i,j)=L1;
                            elseif((I(i,j)>=bx)&&(I(i,j)<=by))
                                I(i,j)=L2;
                            end
                        end
                    end
                    s= I;
                    result_title='Intensity-Level Slicing';
                otherwise
                                
            end  
            imshow(s,'parent',app.AfterUIAxes);
            app.AfterUIAxes.Title.String = result_title;
            
            histogram(app.AfterHistUIAxes, s,255);
            app.ProcessedImg=s;
        end

        % Button pushed function: HighBoostFilterButton
        function HighBoostFilterButtonPushed(app, event)
            if(app.HighboostCheckBox.Value==1)
                mask=app.GrayRawImage-app.SDFilteredImg1;
                highboostimg=app.GrayRawImage+app.HighBoost_k.Value .* mask;
                
                imshow(app.GrayRawImage,'parent',app.SDFilteringUIAxes0);
                app.SDFilteringUIAxes0.Title.String = 'a:Original';
                
                imshow(app.SDFilteredImg1,'parent',app.SDFilteringUIAxes1);
                app.SDFilteringUIAxes1.Title.String = 'b:Flurred';
                
                imshow(mask,'parent',app.SDFilteringUIAxes2);
                app.SDFilteringUIAxes2.Title.String = 'c:Mask(a-b)';
                
                imshow(highboostimg,'parent',app.SDFilteringUIAxes3);
                app.SDFilteringUIAxes3.Title.String = 'd:a+k*c';
                app.SDFilteredImg2 = highboostimg;
                
                if(app.ResultnumberEditField.Value == 1)
                    app.GrayRawImage =app.SDFilteredImg1;
                elseif (app.ResultnumberEditField.Value == 2)
                    app.GrayRawImage =app.SDFilteredImg2;
                elseif (app.ResultnumberEditField.Value == 3)
                    app.GrayRawImage =app.SDFilteredImg3;
                end
                
                
            end
        end

        % Button pushed function: IntensityKeepProcessButton
        function IntensityKeepProcessButtonPushed(app, event)
            app.GrayRawImage =app.ProcessedImg;
            imshow(app.GrayRawImage,'parent',app.OriginalUIAxes);
            cla(app.AfterHistUIAxes,"reset");
            cla(app.OriginalHistUIAxes,"reset");
            cla(app.AfterUIAxes,"reset");
            ShowImageBaseInfo(app);
        end

        % Button pushed function: SDFilterKeepProcessingButton
        function SDFilterKeepProcessingButtonPushed(app, event)
            if(app.ResultnumberEditField.Value == 1)
                app.GrayRawImage =app.SDFilteredImg1;
            elseif (app.ResultnumberEditField.Value == 2)
                app.GrayRawImage =app.SDFilteredImg2;
            elseif (app.ResultnumberEditField.Value == 3)
                app.GrayRawImage =app.SDFilteredImg3;
            end
                
            imshow(app.GrayRawImage,'parent',app.SDFilteringUIAxes0);
            cla(app.SDFilteringUIAxes1,"reset");
            cla(app.SDFilteringUIAxes2,"reset");
            cla(app.SDFilteringUIAxes3,"reset");
            ShowImageBaseInfo(app);
        end

        % Button pushed function: FDFilteringKeepProcessingButton
        function FDFilteringKeepProcessingButtonPushed(app, event)
            app.GrayRawImage =app.FDFilteredImg;
            imshow(app.GrayRawImage,'parent',app.FDFilteringUIAxes0);
            cla(app.FDFilteringUIAxes1,"reset");
            cla(app.FDFilteringUIAxes2,"reset");
            cla(app.FDFilteringUIAxes3,"reset");
            ShowImageBaseInfo(app);
        end

        % Button pushed function: NosieReduceKeepProcessingButton
        function NosieReduceKeepProcessingButtonPushed(app, event)
            app.GrayRawImage =app.ProcessedImg;
            imshow(app.GrayRawImage,'parent',app.NoiseReductionUIAxes0);
            cla(app.NoiseReductionUIAxes1,"reset");
            cla(app.NoiseReductionUIAxes2,"reset");
            cla(app.NoiseReductionUIAxes3,"reset");
            ShowImageBaseInfo(app);
        end

        % Button pushed function: UnsharpMaskingButton
        function UnsharpMaskingButtonPushed(app, event)
            g=app.GrayRawImage+app.UnsharpMasking_k.Value .* app.FDFilteredImg;
            
            imshow(app.FDFilteredImg,'parent',app.FDFilteringUIAxes2);
            app.FDFilteringUIAxes2.Title.String = 'Unsharp Masking';
                
            imshow(g,'parent',app.FDFilteringUIAxes3);
            app.FDFilteringUIAxes3.Title.String = 'After unsharp Masking';
            
            app.FDFilteredImg = g;
        end

        % Menu selected function: SaveImageMenu
        function SaveImageMenuSelected(app, event)
            %Select location to save image
            [filename,pathname]=uiputfile({'*.jpg','JPG Image';'*.png','PNG Image';'*.bmp','BMP Image';'*.gif','GIF Image'},'Save EDITED image');
            if isequal(filename,0) || isequal(pathname,0)
                return;
            else
                savepath=fullfile(pathname,filename);
            end
            
            %Save the final image which is processed
            saveimg=app.ProcessedImg;
            imwrite(saveimg,savepath);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DIP20WTermProjectAppUIFigure = uifigure('Visible', 'off');
            app.DIP20WTermProjectAppUIFigure.Name = 'DIP 20WTerm Project App';
            app.FileMenu = uimenu(app.DIP20WTermProjectAppUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadImageMenu = uimenu(app.FileMenu);
            app.LoadImageMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadImageMenuSelected, true);
            app.LoadImageMenu.Text = 'LoadImage';
            app.SaveImageMenu = uimenu(app.FileMenu);
            app.SaveImageMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveImageMenuSelected, true);
            app.SaveImageMenu.Text = 'SaveImage';
            app.TabGroup = uitabgroup(app.DIP20WTermProjectAppUIFigure);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.GrayRawImgAxes = uiaxes(app.GeneralTab);
            title(app.GrayRawImgAxes, '')
            xlabel(app.GrayRawImgAxes, '')
            ylabel(app.GrayRawImgAxes, '')
            app.GrayRawImgAxes.XTick = [];
            app.GrayRawImgAxes.XTickLabel = '';
            app.GrayRawImgAxes.YTick = [];
            app.GrayRawImgAxes.YTickLabel = '';
            app.HistogramAxes = uiaxes(app.GeneralTab);
            title(app.HistogramAxes, '')
            xlabel(app.HistogramAxes, '')
            ylabel(app.HistogramAxes, '')
            app.HistogramAxes.XLim = [0 1];
            app.HistogramAxes.XTick = [];
            app.HistogramAxes.YTick = [];
            app.ImageSizeLabel = uilabel(app.GeneralTab);
            app.ImageSizeLabel.Text = 'ImageSize';
            app.ImageSize = uieditfield(app.GeneralTab, 'text');
            app.ImageMeanEditFieldLabel = uilabel(app.GeneralTab);
            app.ImageMeanEditFieldLabel.Text = 'ImageMean';
            app.ImageMean = uieditfield(app.GeneralTab, 'text');
            app.StandardDeviationEditFieldLabel = uilabel(app.GeneralTab);
            app.StandardDeviationEditFieldLabel.Text = 'Standard Deviation';
            app.StandardDeviation = uieditfield(app.GeneralTab, 'text');
            app.FFTAxes = uiaxes(app.GeneralTab);
            title(app.FFTAxes, '')
            xlabel(app.FFTAxes, '')
            ylabel(app.FFTAxes, '')
            app.FFTAxes.XTick = [];
            app.FFTAxes.XTickLabel = '';
            app.FFTAxes.YTick = [];
            app.FFTAxes.YTickLabel = '';
            app.GrayLevelsTab = uitab(app.TabGroup);
            app.GrayLevelsTab.Title = 'Gray Levels';
            app.ReduceGrayLevelButton = uibutton(app.GrayLevelsTab, 'push');
            app.ReduceGrayLevelButton.ButtonPushedFcn = createCallbackFcn(app, @ReduceGrayLevelButtonPushed, true);
            app.ReduceGrayLevelButton.Text = 'Reduce Gray Level';
            app.ShowInFigure = uicheckbox(app.GrayLevelsTab);
            app.ShowInFigure.Text = 'Show In new Window';
            app.GrayLevel255UIAxes = uiaxes(app.GrayLevelsTab);
            title(app.GrayLevel255UIAxes, 'Title')
            xlabel(app.GrayLevel255UIAxes, '')
            ylabel(app.GrayLevel255UIAxes, '')
            app.GrayLevel255UIAxes.XTick = [];
            app.GrayLevel255UIAxes.YTick = [];
            app.GrayLevel128UIAxes = uiaxes(app.GrayLevelsTab);
            title(app.GrayLevel128UIAxes, 'Title')
            xlabel(app.GrayLevel128UIAxes, '')
            ylabel(app.GrayLevel128UIAxes, '')
            app.GrayLevel128UIAxes.XTick = [];
            app.GrayLevel128UIAxes.YTick = [];
            app.GrayLevel64UIAxes = uiaxes(app.GrayLevelsTab);
            title(app.GrayLevel64UIAxes, 'Title')
            xlabel(app.GrayLevel64UIAxes, '')
            ylabel(app.GrayLevel64UIAxes, '')
            app.GrayLevel64UIAxes.XTick = [];
            app.GrayLevel64UIAxes.YTick = [];
            app.GrayLevel32UIAxes = uiaxes(app.GrayLevelsTab);
            title(app.GrayLevel32UIAxes, 'Title')
            xlabel(app.GrayLevel32UIAxes, '')
            ylabel(app.GrayLevel32UIAxes, '')
            app.GrayLevel32UIAxes.XTick = [];
            app.GrayLevel32UIAxes.YTick = [];
            app.GrayLevel16UIAxes = uiaxes(app.GrayLevelsTab);
            title(app.GrayLevel16UIAxes, 'Title')
            xlabel(app.GrayLevel16UIAxes, '')
            ylabel(app.GrayLevel16UIAxes, '')
            app.GrayLevel16UIAxes.XTick = [];
            app.GrayLevel16UIAxes.YTick = [];
            app.GrayLevel8UIAxes = uiaxes(app.GrayLevelsTab);
            title(app.GrayLevel8UIAxes, 'Title')
            xlabel(app.GrayLevel8UIAxes, '')
            ylabel(app.GrayLevel8UIAxes, '')
            app.GrayLevel8UIAxes.XTick = [];
            app.GrayLevel8UIAxes.YTick = [];
            app.GrayLevel4UIAxes = uiaxes(app.GrayLevelsTab);
            title(app.GrayLevel4UIAxes, 'Title')
            xlabel(app.GrayLevel4UIAxes, '')
            ylabel(app.GrayLevel4UIAxes, '')
            app.GrayLevel4UIAxes.XTick = [];
            app.GrayLevel4UIAxes.YTick = [];
            app.GrayLevel2UIAxes = uiaxes(app.GrayLevelsTab);
            title(app.GrayLevel2UIAxes, 'Title')
            xlabel(app.GrayLevel2UIAxes, '')
            ylabel(app.GrayLevel2UIAxes, '')
            app.GrayLevel2UIAxes.XTick = [];
            app.GrayLevel2UIAxes.YTick = [];
            app.ResizeTab = uitab(app.TabGroup);
            app.ResizeTab.Title = 'Resize';
            app.ResizeRatioEditFieldLabel = uilabel(app.ResizeTab);
            app.ResizeRatioEditFieldLabel.Text = 'Resize Ratio';
            app.ResizeRatioEditField = uieditfield(app.ResizeTab, 'numeric');
            app.ResizeRatioEditField.ValueChangedFcn = createCallbackFcn(app, @ResizeRatioEditFieldValueChanged, true);
            app.ResizeRatioEditField.Value = 1;
            app.ResizeUIAxes0 = uiaxes(app.ResizeTab);
            title(app.ResizeUIAxes0, 'Title')
            xlabel(app.ResizeUIAxes0, '')
            ylabel(app.ResizeUIAxes0, '')
            app.ResizeUIAxes0.XTick = [];
            app.ResizeUIAxes0.YTick = [];
            app.ResizeUIAxes1 = uiaxes(app.ResizeTab);
            title(app.ResizeUIAxes1, 'Title')
            xlabel(app.ResizeUIAxes1, '')
            ylabel(app.ResizeUIAxes1, '')
            app.ResizeUIAxes1.XTick = [];
            app.ResizeUIAxes1.YTick = [];
            app.ResizeUIAxes2 = uiaxes(app.ResizeTab);
            title(app.ResizeUIAxes2, 'Title')
            xlabel(app.ResizeUIAxes2, '')
            ylabel(app.ResizeUIAxes2, '')
            app.ResizeUIAxes2.XTick = [];
            app.ResizeUIAxes2.YTick = [];
            app.ResizeUIAxes3 = uiaxes(app.ResizeTab);
            title(app.ResizeUIAxes3, {'Title'; ''})
            xlabel(app.ResizeUIAxes3, '')
            ylabel(app.ResizeUIAxes3, '')
            app.ResizeUIAxes3.XTick = [];
            app.ResizeUIAxes3.YTick = [];
            app.IntensityTransformationTab = uitab(app.TabGroup);
            app.IntensityTransformationTab.Title = 'Intensity Transformation';
            app.TransformationUIAxes = uiaxes(app.IntensityTransformationTab);
            title(app.TransformationUIAxes, 'Transformation')
            xlabel(app.TransformationUIAxes, '')
            ylabel(app.TransformationUIAxes, '')
            app.AfterUIAxes = uiaxes(app.IntensityTransformationTab);
            title(app.AfterUIAxes, 'After')
            xlabel(app.AfterUIAxes, '')
            ylabel(app.AfterUIAxes, '')
            app.AfterUIAxes.XTick = [];
            app.AfterUIAxes.YTick = [];
            app.TransformationsButtonGroup = uibuttongroup(app.IntensityTransformationTab);
            app.TransformationsButtonGroup.Title = 'Transformations';
            app.NegativeButton = uiradiobutton(app.TransformationsButtonGroup);
            app.NegativeButton.Text = 'Negative';
            app.NegativeButton.Value = true;
            app.LogrithmButton = uiradiobutton(app.TransformationsButtonGroup);
            app.LogrithmButton.Text = 'Logrithm';
            app.PowerLawButton = uiradiobutton(app.TransformationsButtonGroup);
            app.PowerLawButton.Text = 'PowerLaw';
            app.PiecewiselinearButton = uiradiobutton(app.TransformationsButtonGroup);
            app.PiecewiselinearButton.Text = 'Piecewise-linear';
            app.HistogramEqualizationButton = uiradiobutton(app.TransformationsButtonGroup);
            app.HistogramEqualizationButton.Text = 'Histogram Equalization';
            app.Label = uilabel(app.TransformationsButtonGroup);
            app.Label.Text = 'γ';
            app.PowerLawY = uieditfield(app.TransformationsButtonGroup, 'numeric');
            app.PowerLawY.Value = 2;
            app.vLabel = uilabel(app.TransformationsButtonGroup);
            app.vLabel.Text = 'v';
            app.LogPara_v = uieditfield(app.TransformationsButtonGroup, 'numeric');
            app.LogPara_v.Value = 10;
            app.Piecewise_ay = uieditfield(app.TransformationsButtonGroup, 'numeric');
            app.Piecewise_ay.ValueChangedFcn = createCallbackFcn(app, @Piecewise_ayValueChanged, true);
            app.aLabel = uilabel(app.TransformationsButtonGroup);
            app.aLabel.Text = 'a';
            app.Piecewise_ax = uieditfield(app.TransformationsButtonGroup, 'numeric');
            app.Piecewise_ax.ValueChangedFcn = createCallbackFcn(app, @Piecewise_axValueChanged, true);
            app.Piecewise_ax.Value = 0.5;
            app.Piecewise_bx = uieditfield(app.TransformationsButtonGroup, 'numeric');
            app.Piecewise_bx.ValueChangedFcn = createCallbackFcn(app, @Piecewise_bxValueChanged, true);
            app.Piecewise_bx.Value = 0.5;
            app.Piecewise_by = uieditfield(app.TransformationsButtonGroup, 'numeric');
            app.Piecewise_by.ValueChangedFcn = createCallbackFcn(app, @Piecewise_byValueChanged, true);
            app.Piecewise_by.Value = 1;
            app.bLabel = uilabel(app.TransformationsButtonGroup);
            app.bLabel.Text = 'b';
            app.cLabel = uilabel(app.TransformationsButtonGroup);
            app.cLabel.Text = 'c';
            app.LogPara_c = uieditfield(app.TransformationsButtonGroup, 'numeric');
            app.LogPara_c.Value = 1;
            app.LocalHistogramEqualizationButton = uiradiobutton(app.TransformationsButtonGroup);
            app.LocalHistogramEqualizationButton.Text = 'Local Histogram Equalization';
            app.MaskEditFieldLabel_2 = uilabel(app.TransformationsButtonGroup);
            app.MaskEditFieldLabel_2.Text = 'Mask';
            app.LocalHE_Masksize = uieditfield(app.TransformationsButtonGroup, 'numeric');
            app.LocalHE_Masksize.Value = 3;
            app.LocalHistogramStatisticsButton = uiradiobutton(app.TransformationsButtonGroup);
            app.LocalHistogramStatisticsButton.Text = 'Local Histogram Statistics';
            app.EEditFieldLabel = uilabel(app.TransformationsButtonGroup);
            app.EEditFieldLabel.Text = 'E';
            app.LHS_E = uieditfield(app.TransformationsButtonGroup, 'numeric');
            app.LHS_E.Value = 5;
            app.k0EditFieldLabel = uilabel(app.TransformationsButtonGroup);
            app.k0EditFieldLabel.Text = 'k0';
            app.LHS_k0 = uieditfield(app.TransformationsButtonGroup, 'numeric');
            app.LHS_k0.Value = 0.4;
            app.k1EditFieldLabel = uilabel(app.TransformationsButtonGroup);
            app.k1EditFieldLabel.Text = 'k1';
            app.LHS_k1 = uieditfield(app.TransformationsButtonGroup, 'numeric');
            app.LHS_k1.Value = 0.01;
            app.k2EditFieldLabel = uilabel(app.TransformationsButtonGroup);
            app.k2EditFieldLabel.Text = 'k2';
            app.LHS_k2 = uieditfield(app.TransformationsButtonGroup, 'numeric');
            app.LHS_k2.Value = 0.4;
            app.IntensityLevelSlicingButton = uiradiobutton(app.TransformationsButtonGroup);
            app.IntensityLevelSlicingButton.Text = 'Intensity-Level Slicing';
            app.axEditFieldLabel = uilabel(app.TransformationsButtonGroup);
            app.axEditFieldLabel.Text = 'ax';
            app.IntensityLevelSlicing_ax = uieditfield(app.TransformationsButtonGroup, 'numeric');
            app.IntensityLevelSlicing_ax.Value = 0.1;
            app.ayEditFieldLabel = uilabel(app.TransformationsButtonGroup);
            app.ayEditFieldLabel.Text = 'ay';
            app.IntensityLevelSlicing_ay = uieditfield(app.TransformationsButtonGroup, 'numeric');
            app.IntensityLevelSlicing_ay.Value = 0.2;
            app.bxEditFieldLabel = uilabel(app.TransformationsButtonGroup);
            app.bxEditFieldLabel.Text = 'bx';
            app.IntensityLevelSlicing_bx = uieditfield(app.TransformationsButtonGroup, 'numeric');
            app.IntensityLevelSlicing_bx.Value = 0.5;
            app.byEditField_5Label = uilabel(app.TransformationsButtonGroup);
            app.byEditField_5Label.Text = 'by';
            app.IntensityLevelSlicing_by = uieditfield(app.TransformationsButtonGroup, 'numeric');
            app.IntensityLevelSlicing_by.Value = 0.6;
            app.Level1EditFieldLabel = uilabel(app.TransformationsButtonGroup);
            app.Level1EditFieldLabel.Text = 'Level1';
            app.IntensityLevelSlicing_L1 = uieditfield(app.TransformationsButtonGroup, 'numeric');
            app.IntensityLevelSlicing_L1.Value = 1;
            app.Level2EditFieldLabel = uilabel(app.TransformationsButtonGroup);
            app.Level2EditFieldLabel.Text = 'Level2';
            app.IntensityLevelSlicing_L2 = uieditfield(app.TransformationsButtonGroup, 'numeric');
            app.TransformButton = uibutton(app.IntensityTransformationTab, 'push');
            app.TransformButton.ButtonPushedFcn = createCallbackFcn(app, @TransformButtonPushed, true);
            app.TransformButton.Text = 'Transform';
            app.OriginalUIAxes = uiaxes(app.IntensityTransformationTab);
            title(app.OriginalUIAxes, 'Original')
            xlabel(app.OriginalUIAxes, '')
            ylabel(app.OriginalUIAxes, '')
            app.OriginalUIAxes.XTick = [];
            app.OriginalUIAxes.YTick = [];
            app.AfterHistUIAxes = uiaxes(app.IntensityTransformationTab);
            title(app.AfterHistUIAxes, 'After''s Historgram')
            xlabel(app.AfterHistUIAxes, '')
            ylabel(app.AfterHistUIAxes, '')
            app.AfterHistUIAxes.XLim = [0 1];
            app.AfterHistUIAxes.XTick = [];
            app.AfterHistUIAxes.YTick = [];
            app.OriginalHistUIAxes = uiaxes(app.IntensityTransformationTab);
            title(app.OriginalHistUIAxes, 'Original''s Histogram')
            xlabel(app.OriginalHistUIAxes, '')
            ylabel(app.OriginalHistUIAxes, '')
            app.OriginalHistUIAxes.XLim = [0 1];
            app.OriginalHistUIAxes.XTick = [];
            app.OriginalHistUIAxes.YTick = [];
            app.IntensityKeepProcessButton = uibutton(app.IntensityTransformationTab, 'push');
            app.IntensityKeepProcessButton.ButtonPushedFcn = createCallbackFcn(app, @IntensityKeepProcessButtonPushed, true);
            app.IntensityKeepProcessButton.Text = 'Use the result to contunue process';
            app.SDFilteringTab = uitab(app.TabGroup);
            app.SDFilteringTab.Title = 'SD Filtering';
            app.FilterTypeDropDownLabel = uilabel(app.SDFilteringTab);
            app.FilterTypeDropDownLabel.Visible = 'off';
            app.FilterTypeDropDownLabel.Text = 'Filter Type';
            app.FilterTypeDropDown = uidropdown(app.SDFilteringTab);
            app.FilterTypeDropDown.Items = {'Average', 'Median', 'Gaussian', 'Laplacian', 'Prewitt', 'Sobel', 'ContraHarm'};
            app.FilterTypeDropDown.Visible = 'off';
            app.FilterTypeDropDown.Value = 'Average';
            app.SDFilterButton = uibutton(app.SDFilteringTab, 'push');
            app.SDFilterButton.ButtonPushedFcn = createCallbackFcn(app, @SDFilterButtonPushed, true);
            app.SDFilterButton.Text = 'SD Filter';
            app.SmoothingPanel = uipanel(app.SDFilteringTab);
            app.SmoothingPanel.Title = 'Smoothing';
            app.AverageCheckBox = uicheckbox(app.SmoothingPanel);
            app.AverageCheckBox.Text = 'Average';
            app.MedianCheckBox = uicheckbox(app.SmoothingPanel);
            app.MedianCheckBox.Text = 'Median';
            app.GaussianCheckBox = uicheckbox(app.SmoothingPanel);
            app.GaussianCheckBox.Text = 'Gaussian';
            app.AverageMask1 = uieditfield(app.SmoothingPanel, 'numeric');
            app.AverageMask1.Value = 3;
            app.AverageMask2 = uieditfield(app.SmoothingPanel, 'numeric');
            app.AverageMask2.Value = 7;
            app.AverageMask3 = uieditfield(app.SmoothingPanel, 'numeric');
            app.AverageMask3.Value = 9;
            app.MedianMask1 = uieditfield(app.SmoothingPanel, 'numeric');
            app.MedianMask1.Value = 3;
            app.MedianMask2 = uieditfield(app.SmoothingPanel, 'numeric');
            app.MedianMask2.Value = 4;
            app.MedianMask3 = uieditfield(app.SmoothingPanel, 'numeric');
            app.MedianMask3.Value = 5;
            app.GaussianSigma1 = uieditfield(app.SmoothingPanel, 'numeric');
            app.GaussianSigma1.Value = 0.5;
            app.GaussianSigma2 = uieditfield(app.SmoothingPanel, 'numeric');
            app.GaussianSigma2.Value = 1;
            app.GaussianSigma3 = uieditfield(app.SmoothingPanel, 'numeric');
            app.GaussianSigma3.Value = 4;
            app.ContraharmMask1 = uieditfield(app.SmoothingPanel, 'numeric');
            app.ContraharmMask1.Visible = 'off';
            app.ContraharmMask1.Value = 3;
            app.ContraharmMask2 = uieditfield(app.SmoothingPanel, 'numeric');
            app.ContraharmMask2.Visible = 'off';
            app.ContraharmMask2.Value = 7;
            app.ContraharmMask3 = uieditfield(app.SmoothingPanel, 'numeric');
            app.ContraharmMask3.Visible = 'off';
            app.ContraharmMask3.Value = 9;
            app.QEditFieldLabel = uilabel(app.SmoothingPanel);
            app.QEditFieldLabel.Visible = 'off';
            app.QEditFieldLabel.Text = 'Q';
            app.ContraharmQ = uieditfield(app.SmoothingPanel, 'numeric');
            app.ContraharmQ.Visible = 'off';
            app.MaskLabel_2 = uilabel(app.SmoothingPanel);
            app.MaskLabel_2.Text = 'Mask';
            app.MaskLabel_3 = uilabel(app.SmoothingPanel);
            app.MaskLabel_3.Text = 'Mask';
            app.SigmaLabel = uilabel(app.SmoothingPanel);
            app.SigmaLabel.Text = 'Sigma';
            app.MaskEditFieldLabel_4 = uilabel(app.SmoothingPanel);
            app.MaskEditFieldLabel_4.Text = 'Mask';
            app.SD_Gaussian_mask = uieditfield(app.SmoothingPanel, 'numeric');
            app.SD_Gaussian_mask.Value = 3;
            app.SharpeningPanel = uipanel(app.SDFilteringTab);
            app.SharpeningPanel.Title = 'Sharpening';
            app.LaplacianCheckBox = uicheckbox(app.SharpeningPanel);
            app.LaplacianCheckBox.Text = 'Laplacian';
            app.PrewittCheckBox = uicheckbox(app.SharpeningPanel);
            app.PrewittCheckBox.Text = 'Prewitt';
            app.SobelCheckBox = uicheckbox(app.SharpeningPanel);
            app.SobelCheckBox.Text = 'Sobel';
            app.LaplacianAlpha = uieditfield(app.SharpeningPanel, 'numeric');
            app.LaplacianAlpha.Value = 0.2;
            app.AlphaLabel = uilabel(app.SharpeningPanel);
            app.AlphaLabel.Text = 'Alpha';
            app.kEditFieldLabel_2 = uilabel(app.SharpeningPanel);
            app.kEditFieldLabel_2.Text = 'k';
            app.Laplacian_k = uieditfield(app.SharpeningPanel, 'numeric');
            app.Laplacian_k.Value = 1;
            app.kEditField_2Label = uilabel(app.SharpeningPanel);
            app.kEditField_2Label.Text = 'k';
            app.Prewitt_k = uieditfield(app.SharpeningPanel, 'numeric');
            app.Prewitt_k.Value = 1;
            app.kEditField_3Label = uilabel(app.SharpeningPanel);
            app.kEditField_3Label.Text = 'k';
            app.Sobel_k = uieditfield(app.SharpeningPanel, 'numeric');
            app.Sobel_k.Value = 1;
            app.transposeCheckBox = uicheckbox(app.SharpeningPanel);
            app.transposeCheckBox.Text = 'transpose ';
            app.SDFilteringUIAxes0 = uiaxes(app.SDFilteringTab);
            title(app.SDFilteringUIAxes0, 'Title')
            xlabel(app.SDFilteringUIAxes0, '')
            ylabel(app.SDFilteringUIAxes0, '')
            app.SDFilteringUIAxes0.XTick = [];
            app.SDFilteringUIAxes0.YTick = [];
            app.SDFilteringUIAxes1 = uiaxes(app.SDFilteringTab);
            title(app.SDFilteringUIAxes1, 'Title')
            xlabel(app.SDFilteringUIAxes1, '')
            ylabel(app.SDFilteringUIAxes1, '')
            app.SDFilteringUIAxes1.XTick = [];
            app.SDFilteringUIAxes1.YTick = [];
            app.SDFilteringUIAxes2 = uiaxes(app.SDFilteringTab);
            title(app.SDFilteringUIAxes2, 'Title')
            xlabel(app.SDFilteringUIAxes2, '')
            ylabel(app.SDFilteringUIAxes2, '')
            app.SDFilteringUIAxes2.XTick = [];
            app.SDFilteringUIAxes2.YTick = [];
            app.SDFilteringUIAxes3 = uiaxes(app.SDFilteringTab);
            title(app.SDFilteringUIAxes3, 'Title')
            xlabel(app.SDFilteringUIAxes3, '')
            ylabel(app.SDFilteringUIAxes3, '')
            app.SDFilteringUIAxes3.XTick = [];
            app.SDFilteringUIAxes3.YTick = [];
            app.ContraharmCheckBox = uicheckbox(app.SDFilteringTab);
            app.ContraharmCheckBox.Visible = 'off';
            app.ContraharmCheckBox.Text = 'Contraharm';
            app.MaskLabel = uilabel(app.SDFilteringTab);
            app.MaskLabel.Visible = 'off';
            app.MaskLabel.Text = 'Mask';
            app.HighboostCheckBox = uicheckbox(app.SDFilteringTab);
            app.HighboostCheckBox.Text = 'Highboost';
            app.kEditFieldLabel = uilabel(app.SDFilteringTab);
            app.kEditFieldLabel.Text = 'k';
            app.HighBoost_k = uieditfield(app.SDFilteringTab, 'numeric');
            app.HighBoost_k.Value = 2;
            app.HighBoostFilterButton = uibutton(app.SDFilteringTab, 'push');
            app.HighBoostFilterButton.ButtonPushedFcn = createCallbackFcn(app, @HighBoostFilterButtonPushed, true);
            app.HighBoostFilterButton.Text = 'Filter';
            app.SDFilterKeepProcessingButton = uibutton(app.SDFilteringTab, 'push');
            app.SDFilterKeepProcessingButton.ButtonPushedFcn = createCallbackFcn(app, @SDFilterKeepProcessingButtonPushed, true);
            app.SDFilterKeepProcessingButton.Text = 'Use the result to continue processing';
            app.ResultnumberEditFieldLabel = uilabel(app.SDFilteringTab);
            app.ResultnumberEditFieldLabel.Text = 'Result number';
            app.ResultnumberEditField = uieditfield(app.SDFilteringTab, 'numeric');
            app.ResultnumberEditField.Value = 1;
            app.FDFilteringTab = uitab(app.TabGroup);
            app.FDFilteringTab.Title = 'FD Filtering';
            app.FilterTypeDropDown_2Label = uilabel(app.FDFilteringTab);
            app.FilterTypeDropDown_2Label.Visible = 'off';
            app.FilterTypeDropDown_2Label.Text = 'Filter Type';
            app.FilterTypeDropDown_2 = uidropdown(app.FDFilteringTab);
            app.FilterTypeDropDown_2.Items = {'IdealLowPass', 'GuassianLowPass', 'ButterworthLowPass', 'IdealHighPass', 'GuassianHighPass', 'ButterworthHighPass'};
            app.FilterTypeDropDown_2.Visible = 'off';
            app.FilterTypeDropDown_2.Value = 'IdealLowPass';
            app.FDFilterButton = uibutton(app.FDFilteringTab, 'push');
            app.FDFilterButton.ButtonPushedFcn = createCallbackFcn(app, @FDFilterButtonPushed, true);
            app.FDFilterButton.Text = 'FD Filter';
            app.SmoothingPanel_2 = uipanel(app.FDFilteringTab);
            app.SmoothingPanel_2.Title = 'Smoothing';
            app.IdealLowPassCheckBox = uicheckbox(app.SmoothingPanel_2);
            app.IdealLowPassCheckBox.Text = 'IdealLowPass';
            app.GaussianLowPassCheckBox = uicheckbox(app.SmoothingPanel_2);
            app.GaussianLowPassCheckBox.Text = 'GaussianLowPass';
            app.ButterworthLowPassCheckBox = uicheckbox(app.SmoothingPanel_2);
            app.ButterworthLowPassCheckBox.Text = 'ButterworthLowPass';
            app.D0EditFieldLabel = uilabel(app.SmoothingPanel_2);
            app.D0EditFieldLabel.Text = 'D0';
            app.IdealLowPassD0 = uieditfield(app.SmoothingPanel_2, 'numeric');
            app.IdealLowPassD0.Value = 30;
            app.D0EditField_2Label = uilabel(app.SmoothingPanel_2);
            app.D0EditField_2Label.Text = 'D0';
            app.GaussianLowPassD0 = uieditfield(app.SmoothingPanel_2, 'numeric');
            app.GaussianLowPassD0.Value = 30;
            app.D0EditField_3Label = uilabel(app.SmoothingPanel_2);
            app.D0EditField_3Label.Text = 'D0';
            app.ButterworthLowPassD0 = uieditfield(app.SmoothingPanel_2, 'numeric');
            app.ButterworthLowPassD0.Value = 30;
            app.nEditFieldLabel = uilabel(app.SmoothingPanel_2);
            app.nEditFieldLabel.Text = 'n';
            app.ButterworthLowPassn = uieditfield(app.SmoothingPanel_2, 'numeric');
            app.ButterworthLowPassn.Value = 2;
            app.ButterworthNotchFilterCheckBox = uicheckbox(app.SmoothingPanel_2);
            app.ButterworthNotchFilterCheckBox.Text = 'ButterworthNotchFilter';
            app.D0EditField_3Label_2 = uilabel(app.SmoothingPanel_2);
            app.D0EditField_3Label_2.Text = 'D0';
            app.ButterworthNotchFilterD0 = uieditfield(app.SmoothingPanel_2, 'numeric');
            app.ButterworthNotchFilterD0.Value = 30;
            app.Uk1EditFieldLabel = uilabel(app.SmoothingPanel_2);
            app.Uk1EditFieldLabel.Text = 'Uk1';
            app.Uk1EditField = uieditfield(app.SmoothingPanel_2, 'numeric');
            app.Uk1EditField.Value = 77;
            app.Vk1EditFieldLabel = uilabel(app.SmoothingPanel_2);
            app.Vk1EditFieldLabel.Text = 'Vk1';
            app.Vk1EditField = uieditfield(app.SmoothingPanel_2, 'numeric');
            app.Vk1EditField.Value = 59;
            app.Uk2EditFieldLabel = uilabel(app.SmoothingPanel_2);
            app.Uk2EditFieldLabel.Text = 'Uk2';
            app.Uk2EditField = uieditfield(app.SmoothingPanel_2, 'numeric');
            app.Uk2EditField.Value = 159;
            app.Vk2EditFieldLabel = uilabel(app.SmoothingPanel_2);
            app.Vk2EditFieldLabel.Text = 'Vk2';
            app.Vk2EditField = uieditfield(app.SmoothingPanel_2, 'numeric');
            app.Vk2EditField.Value = 59;
            app.Uk3EditFieldLabel = uilabel(app.SmoothingPanel_2);
            app.Uk3EditFieldLabel.Text = 'Uk3';
            app.Uk3EditField = uieditfield(app.SmoothingPanel_2, 'numeric');
            app.Uk3EditField.Value = 84;
            app.Vk3EditFieldLabel = uilabel(app.SmoothingPanel_2);
            app.Vk3EditFieldLabel.Text = 'Vk3';
            app.Vk3EditField = uieditfield(app.SmoothingPanel_2, 'numeric');
            app.Vk3EditField.Value = -54;
            app.Uk4EditFieldLabel = uilabel(app.SmoothingPanel_2);
            app.Uk4EditFieldLabel.Text = 'Uk4';
            app.Uk4EditField = uieditfield(app.SmoothingPanel_2, 'numeric');
            app.Uk4EditField.Value = 167;
            app.Vk4EditFieldLabel = uilabel(app.SmoothingPanel_2);
            app.Vk4EditFieldLabel.Text = 'Vk4';
            app.Vk4EditField = uieditfield(app.SmoothingPanel_2, 'numeric');
            app.Vk4EditField.Value = -54;
            app.SharpeningPanel_2 = uipanel(app.FDFilteringTab);
            app.SharpeningPanel_2.Title = 'Sharpening';
            app.IdealHighPassCheckBox = uicheckbox(app.SharpeningPanel_2);
            app.IdealHighPassCheckBox.Text = 'IdealHighPass';
            app.GaussianHighPassCheckBox = uicheckbox(app.SharpeningPanel_2);
            app.GaussianHighPassCheckBox.Text = 'GaussianHighPass';
            app.ButterworthHighPassCheckBox = uicheckbox(app.SharpeningPanel_2);
            app.ButterworthHighPassCheckBox.Text = 'ButterworthHighPass';
            app.D0EditField_4Label = uilabel(app.SharpeningPanel_2);
            app.D0EditField_4Label.Text = 'D0';
            app.IdealHighPassD0 = uieditfield(app.SharpeningPanel_2, 'numeric');
            app.IdealHighPassD0.Value = 30;
            app.D0EditField_5Label = uilabel(app.SharpeningPanel_2);
            app.D0EditField_5Label.Text = 'D0';
            app.GaussianHighPassD0 = uieditfield(app.SharpeningPanel_2, 'numeric');
            app.GaussianHighPassD0.Value = 30;
            app.D0EditField_6Label = uilabel(app.SharpeningPanel_2);
            app.D0EditField_6Label.Text = 'D0';
            app.ButterworthHighPassD0 = uieditfield(app.SharpeningPanel_2, 'numeric');
            app.ButterworthHighPassD0.Value = 30;
            app.nEditField_2Label = uilabel(app.SharpeningPanel_2);
            app.nEditField_2Label.Text = 'n';
            app.ButterworthHighPassn = uieditfield(app.SharpeningPanel_2, 'numeric');
            app.ButterworthHighPassn.Value = 2;
            app.FDFilteringUIAxes0 = uiaxes(app.FDFilteringTab);
            title(app.FDFilteringUIAxes0, 'Title')
            xlabel(app.FDFilteringUIAxes0, '')
            ylabel(app.FDFilteringUIAxes0, '')
            app.FDFilteringUIAxes0.XTick = [];
            app.FDFilteringUIAxes0.YTick = [];
            app.FDFilteringUIAxes1 = uiaxes(app.FDFilteringTab);
            title(app.FDFilteringUIAxes1, 'Title')
            xlabel(app.FDFilteringUIAxes1, '')
            ylabel(app.FDFilteringUIAxes1, '')
            app.FDFilteringUIAxes1.XTick = [];
            app.FDFilteringUIAxes1.YTick = [];
            app.FDFilteringUIAxes2 = uiaxes(app.FDFilteringTab);
            title(app.FDFilteringUIAxes2, 'Title')
            xlabel(app.FDFilteringUIAxes2, '')
            ylabel(app.FDFilteringUIAxes2, '')
            app.FDFilteringUIAxes2.XTick = [];
            app.FDFilteringUIAxes2.YTick = [];
            app.FDFilteringUIAxes3 = uiaxes(app.FDFilteringTab);
            title(app.FDFilteringUIAxes3, 'Title')
            xlabel(app.FDFilteringUIAxes3, '')
            ylabel(app.FDFilteringUIAxes3, '')
            app.FDFilteringUIAxes3.XTick = [];
            app.FDFilteringUIAxes3.YTick = [];
            app.FDFilteringKeepProcessingButton = uibutton(app.FDFilteringTab, 'push');
            app.FDFilteringKeepProcessingButton.ButtonPushedFcn = createCallbackFcn(app, @FDFilteringKeepProcessingButtonPushed, true);
            app.FDFilteringKeepProcessingButton.Text = 'Use the result to continue processing';
            app.UnsharpMaskingButton = uibutton(app.FDFilteringTab, 'push');
            app.UnsharpMaskingButton.ButtonPushedFcn = createCallbackFcn(app, @UnsharpMaskingButtonPushed, true);
            app.UnsharpMaskingButton.Text = 'Unsharp Masking';
            app.kEditFieldLabel_3 = uilabel(app.FDFilteringTab);
            app.kEditFieldLabel_3.Text = 'k';
            app.UnsharpMasking_k = uieditfield(app.FDFilteringTab, 'numeric');
            app.UnsharpMasking_k.Value = 1;
            app.NoiseReductionTab = uitab(app.TabGroup);
            app.NoiseReductionTab.Title = 'Noise Reduction';
            app.NoiseReductionPanel = uipanel(app.NoiseReductionTab);
            app.NoiseReductionPanel.Title = 'Noise Reduction';
            app.ArithmeticCheckBox = uicheckbox(app.NoiseReductionPanel);
            app.ArithmeticCheckBox.Text = 'Arithmetic';
            app.GeometricCheckBox = uicheckbox(app.NoiseReductionPanel);
            app.GeometricCheckBox.Text = 'Geometric';
            app.ContraharmonicCheckBox = uicheckbox(app.NoiseReductionPanel);
            app.ContraharmonicCheckBox.Text = 'Contra-harmonic';
            app.OrderStatisticsCheckBox = uicheckbox(app.NoiseReductionPanel);
            app.OrderStatisticsCheckBox.Text = 'Order-Statistics';
            app.AdaptivemeanfiltersCheckBox = uicheckbox(app.NoiseReductionPanel);
            app.AdaptivemeanfiltersCheckBox.Text = 'Adaptive mean filters';
            app.MaskEditFieldLabel = uilabel(app.NoiseReductionPanel);
            app.MaskEditFieldLabel.Text = 'Mask';
            app.ArithmeticMask1 = uieditfield(app.NoiseReductionPanel, 'numeric');
            app.ArithmeticMask1.Value = 3;
            app.MaskEditField_2Label = uilabel(app.NoiseReductionPanel);
            app.MaskEditField_2Label.Text = 'Mask';
            app.GeometricMask1 = uieditfield(app.NoiseReductionPanel, 'numeric');
            app.GeometricMask1.Value = 3;
            app.MaskEditField_3Label = uilabel(app.NoiseReductionPanel);
            app.MaskEditField_3Label.Text = 'Mask';
            app.ContraHarmonicMask1 = uieditfield(app.NoiseReductionPanel, 'numeric');
            app.ContraHarmonicMask1.Value = 3;
            app.MaskEditField_4Label = uilabel(app.NoiseReductionPanel);
            app.MaskEditField_4Label.Text = 'Mask';
            app.OrderStatisticsMask = uieditfield(app.NoiseReductionPanel, 'numeric');
            app.OrderStatisticsMask.Value = 5;
            app.OrderEditFieldLabel = uilabel(app.NoiseReductionPanel);
            app.OrderEditFieldLabel.Text = 'Order';
            app.OrderStatisticsOrder = uieditfield(app.NoiseReductionPanel, 'numeric');
            app.OrderStatisticsOrder.Value = 13;
            app.QEditFieldLabel_2 = uilabel(app.NoiseReductionPanel);
            app.QEditFieldLabel_2.Text = 'Q';
            app.ContraHarmonicQ = uieditfield(app.NoiseReductionPanel, 'numeric');
            app.ContraHarmonicQ.Value = 2;
            app.WienerFilterCheckBox = uicheckbox(app.NoiseReductionPanel);
            app.WienerFilterCheckBox.Text = 'WienerFilter';
            app.MaskEditField_4Label_2 = uilabel(app.NoiseReductionPanel);
            app.MaskEditField_4Label_2.Text = 'Mask';
            app.WienerFilter_mask = uieditfield(app.NoiseReductionPanel, 'numeric');
            app.WienerFilter_mask.Value = 5;
            app.AlphaTrimMeanCheckBox = uicheckbox(app.NoiseReductionPanel);
            app.AlphaTrimMeanCheckBox.Text = 'AlphaTrimMean';
            app.MaskEditField_4Label_3 = uilabel(app.NoiseReductionPanel);
            app.MaskEditField_4Label_3.Text = 'Mask';
            app.AlphaTrimMean_mask = uieditfield(app.NoiseReductionPanel, 'numeric');
            app.AlphaTrimMean_mask.Value = 3;
            app.NoiseReduceButton = uibutton(app.NoiseReductionTab, 'push');
            app.NoiseReduceButton.ButtonPushedFcn = createCallbackFcn(app, @NoiseReduceButtonPushed, true);
            app.NoiseReduceButton.Text = 'Noise Reduce';
            app.NoiseReductionUIAxes0 = uiaxes(app.NoiseReductionTab);
            title(app.NoiseReductionUIAxes0, 'Title')
            xlabel(app.NoiseReductionUIAxes0, '')
            ylabel(app.NoiseReductionUIAxes0, '')
            app.NoiseReductionUIAxes0.XTick = [];
            app.NoiseReductionUIAxes0.YTick = [];
            app.NoiseReductionUIAxes1 = uiaxes(app.NoiseReductionTab);
            title(app.NoiseReductionUIAxes1, 'Title')
            xlabel(app.NoiseReductionUIAxes1, '')
            ylabel(app.NoiseReductionUIAxes1, '')
            app.NoiseReductionUIAxes1.XTick = [];
            app.NoiseReductionUIAxes1.YTick = [];
            app.NoiseReductionUIAxes2 = uiaxes(app.NoiseReductionTab);
            title(app.NoiseReductionUIAxes2, 'Title')
            xlabel(app.NoiseReductionUIAxes2, '')
            ylabel(app.NoiseReductionUIAxes2, '')
            app.NoiseReductionUIAxes2.XTick = [];
            app.NoiseReductionUIAxes2.YTick = [];
            app.NoiseReductionUIAxes3 = uiaxes(app.NoiseReductionTab);
            title(app.NoiseReductionUIAxes3, 'Title')
            xlabel(app.NoiseReductionUIAxes3, '')
            ylabel(app.NoiseReductionUIAxes3, '')
            app.NoiseReductionUIAxes3.XTick = [];
            app.NoiseReductionUIAxes3.YTick = [];
            app.NoiseadditionPanel = uipanel(app.NoiseReductionTab);
            app.NoiseadditionPanel.Title = 'Noise addition';
            app.GaussianNoiseCheckBox = uicheckbox(app.NoiseadditionPanel);
            app.GaussianNoiseCheckBox.Text = 'GaussianNoise';
            app.meanEditFieldLabel = uilabel(app.NoiseadditionPanel);
            app.meanEditFieldLabel.Text = 'mean';
            app.GaussianNoisemean = uieditfield(app.NoiseadditionPanel, 'numeric');
            app.varianceEditFieldLabel = uilabel(app.NoiseadditionPanel);
            app.varianceEditFieldLabel.Text = 'variance';
            app.GaussianNoiseVar = uieditfield(app.NoiseadditionPanel, 'numeric');
            app.GaussianNoiseVar.Value = 0.1;
            app.SaltpepperNoiseCheckBox = uicheckbox(app.NoiseadditionPanel);
            app.SaltpepperNoiseCheckBox.Text = 'Salt&pepperNoise';
            app.PaLabel = uilabel(app.NoiseadditionPanel);
            app.PaLabel.Text = 'Pa';
            app.SaltPepperPa = uieditfield(app.NoiseadditionPanel, 'numeric');
            app.SaltPepperPa.Value = 0.1;
            app.RayleighNoiseCheckBox = uicheckbox(app.NoiseadditionPanel);
            app.RayleighNoiseCheckBox.Text = 'RayleighNoise';
            app.ExponentialNoiseCheckBox = uicheckbox(app.NoiseadditionPanel);
            app.ExponentialNoiseCheckBox.Text = 'ExponentialNoise';
            app.ErlangNoiseCheckBox = uicheckbox(app.NoiseadditionPanel);
            app.ErlangNoiseCheckBox.Text = 'ErlangNoise';
            app.aEditFieldLabel = uilabel(app.NoiseadditionPanel);
            app.aEditFieldLabel.Text = 'a';
            app.RayleighNoiseA = uieditfield(app.NoiseadditionPanel, 'numeric');
            app.bEditFieldLabel = uilabel(app.NoiseadditionPanel);
            app.bEditFieldLabel.Text = 'b';
            app.RayleighNoiseB = uieditfield(app.NoiseadditionPanel, 'numeric');
            app.RayleighNoiseB.Value = 1;
            app.aEditField_2Label = uilabel(app.NoiseadditionPanel);
            app.aEditField_2Label.Text = 'a';
            app.ExponentialNoiseA = uieditfield(app.NoiseadditionPanel, 'numeric');
            app.ExponentialNoiseA.Value = 1;
            app.aEditField_3Label = uilabel(app.NoiseadditionPanel);
            app.aEditField_3Label.Text = 'a';
            app.ErlangNoiseA = uieditfield(app.NoiseadditionPanel, 'numeric');
            app.ErlangNoiseA.Value = 2;
            app.bEditField_2Label = uilabel(app.NoiseadditionPanel);
            app.bEditField_2Label.Text = 'b';
            app.ErlangNoiseB = uieditfield(app.NoiseadditionPanel, 'numeric');
            app.ErlangNoiseB.Value = 5;
            app.PbLabel = uilabel(app.NoiseadditionPanel);
            app.PbLabel.Text = 'Pb';
            app.SaltPepperPb = uieditfield(app.NoiseadditionPanel, 'numeric');
            app.SaltPepperPb.Value = 0.1;
            app.NosieReduceKeepProcessingButton = uibutton(app.NoiseReductionTab, 'push');
            app.NosieReduceKeepProcessingButton.ButtonPushedFcn = createCallbackFcn(app, @NosieReduceKeepProcessingButtonPushed, true);
            app.NosieReduceKeepProcessingButton.Text = 'Use the result to continue processing';
            % Show the figure after all components are created
            app.DIP20WTermProjectAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DIP20WTermProjectAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DIP20WTermProjectAppUIFigure)
        end
    end
end